classdef PRAISysUserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PRAISysUserInterfaceLabel       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        SystemsofInterestPanel          matlab.ui.container.Panel
        PowerCheckBox                   matlab.ui.control.CheckBox
        CommunicationCheckBox           matlab.ui.control.CheckBox
        TransportationCheckBox          matlab.ui.control.CheckBox
        SubComponentAnalysisSwitchLabel  matlab.ui.control.Label
        SubComponentAnalysisSwitch      matlab.ui.control.Switch
        HazardScenarioPanel             matlab.ui.container.Panel
        HazardMapDropDownLabel          matlab.ui.control.Label
        HazardMapDropDown               matlab.ui.control.DropDown
        HazardTypeDropDown_2Label       matlab.ui.control.Label
        HazardTypeDropDown_2            matlab.ui.control.DropDown
        NeighborhoodDataPanel           matlab.ui.container.Panel
        InputDataDropDownLabel          matlab.ui.control.Label
        InputDataDropDown               matlab.ui.control.DropDown
        RandomSamplingPanel             matlab.ui.container.Panel
        SamplingTypeSwitchLabel         matlab.ui.control.Label
        SamplingTypeSwitch              matlab.ui.control.Switch
        NoofTaskDurationSampleperDamageSampleSpinnerLabel  matlab.ui.control.Label
        NoofTaskDurationSampleperDamageSampleSpinner  matlab.ui.control.Spinner
        NoofDamageScenarioSamplesSpinnerLabel  matlab.ui.control.Label
        NoofDamageScenarioSamplesSpinner  matlab.ui.control.Spinner
        RandomSeedSpinnerLabel          matlab.ui.control.Label
        RandomSeedSpinner               matlab.ui.control.Spinner
        TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel  matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        Switch_2                        matlab.ui.control.Switch
        UsingtimeunitofhourintheimmediaterecoveryphaseuntilthedayLabel  matlab.ui.control.Label
        ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner  matlab.ui.control.Spinner
        AfterwardsusingthetimeunitofdayintheintermediumLabel  matlab.ui.control.Label
        timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner  matlab.ui.control.Spinner
        LongtermRecoveryPhaseuntilDaytimeunitWeekSpinnerLabel  matlab.ui.control.Label
        LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner  matlab.ui.control.Spinner
        DisasterManagementOptionsPanel  matlab.ui.container.Panel
        UseextraresourcestorelieffunctionalitydependencySwitchLabel  matlab.ui.control.Label
        UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch  matlab.ui.control.Switch
        RelaxprecendenceconstraintbetweensometasksSwitchLabel  matlab.ui.control.Label
        RelaxprecendenceconstraintbetweensometasksSwitch  matlab.ui.control.Switch
        CoordinaterestorationplanningacrosssystemsSwitch  matlab.ui.control.Switch
        CoordinaterestorationplanningacrosssystemsSwitchLabel  matlab.ui.control.Label
        ImportExporttheInputDataPanel   matlab.ui.container.Panel
        ImportSettingsButton            matlab.ui.control.Button
        NoteTheinputfileisatextfileLabel  matlab.ui.control.Label
        ExportSettingsButton            matlab.ui.control.Button
        ImportedLabel                   matlab.ui.control.Label
        ExportedLabel                   matlab.ui.control.Label
        HistorySavingPanel              matlab.ui.container.Panel
        Switch_3                        matlab.ui.control.Switch
        MiscellaneousPanel              matlab.ui.container.Panel
        ParallelComputingSpinnerLabel   matlab.ui.control.Label
        ParallelComputingSpinner        matlab.ui.control.Spinner
        ProbabilityofthePresenceofaMobileGeneratorLabel  matlab.ui.control.Label
        ProbabilityofthePresenceofaMobileGeneratorSpinner_2  matlab.ui.control.Spinner
        SchedulingTab                   matlab.ui.container.Tab
        DeveloptheRestorationPlanPanel  matlab.ui.container.Panel
        OptimizationChoiceDropDownLabel  matlab.ui.control.Label
        OptimizationChoiceDropDown      matlab.ui.control.DropDown
        RestorationPlanningSchemeSwitchLabel  matlab.ui.control.Label
        RestorationPlanningSchemeSwitch  matlab.ui.control.Switch
        ReSchedulingOptionSwitchLabel   matlab.ui.control.Label
        ReSchedulingOptionSwitch        matlab.ui.control.Switch
        TimeHorizonDaySpinnerLabel      matlab.ui.control.Label
        TimeHorizonDaySpinner           matlab.ui.control.Spinner
        ReSchedulingOptionsPanel        matlab.ui.container.Panel
        RescheduleataCertainTimeDayPanel  matlab.ui.container.Panel
        NoofReschedulesSpinnerLabel     matlab.ui.control.Label
        NoofReschedulesSpinner          matlab.ui.control.Spinner
        streschedulingtimedaySpinnerLabel  matlab.ui.control.Label
        streschedulingtimedaySpinner    matlab.ui.control.Spinner
        ndreschedulingtimedaySpinnerLabel  matlab.ui.control.Label
        ndreschedulingtimedaySpinner    matlab.ui.control.Spinner
        rdreschedulingtimedaySpinnerLabel  matlab.ui.control.Label
        rdreschedulingtimedaySpinner    matlab.ui.control.Spinner
        threschedulingtimedaySpinnerLabel  matlab.ui.control.Label
        threschedulingtimedaySpinner    matlab.ui.control.Spinner
        threschedulingtimedaySpinner_2Label  matlab.ui.control.Label
        threschedulingtimedaySpinner_2  matlab.ui.control.Spinner
        NotePositveintegerlessthanTimeHorizonLabel  matlab.ui.control.Label
        RescheduleataFunctionalityThesholdPanel  matlab.ui.container.Panel
        NoofReschedulesSpinner_2Label   matlab.ui.control.Label
        NoofReschedulesSpinner_2        matlab.ui.control.Spinner
        stSliderLabel                   matlab.ui.control.Label
        stSlider                        matlab.ui.control.Slider
        ndSliderLabel                   matlab.ui.control.Label
        ndSlider                        matlab.ui.control.Slider
        rdSliderLabel                   matlab.ui.control.Label
        rdSlider                        matlab.ui.control.Slider
        thSliderLabel                   matlab.ui.control.Label
        thSlider                        matlab.ui.control.Slider
        thSlider_2Label                 matlab.ui.control.Label
        thSlider_2                      matlab.ui.control.Slider
        PowerTab                        matlab.ui.container.Tab
        PriorityPolicyCriterionButtonGroup  matlab.ui.container.ButtonGroup
        VoltageButton                   matlab.ui.control.RadioButton
        PopulationservedbycomponentButton  matlab.ui.control.RadioButton
        RandomlyButton                  matlab.ui.control.RadioButton
        NoofhouseholdsservedbycomponentButton  matlab.ui.control.RadioButton
        InputFileforComponentsPanel_3   matlab.ui.container.Panel
        PowerPlantCheckBox              matlab.ui.control.CheckBox
        SubstationCheckBox              matlab.ui.control.CheckBox
        PowerlineCheckBox               matlab.ui.control.CheckBox
        TransmissionTowerCheckBox       matlab.ui.control.CheckBox
        FunctionalityMetricSelectionPanel  matlab.ui.container.Panel
        GraphTheoryPropertiesforSystemFunctionalitySwitch_3Label  matlab.ui.control.Label
        GraphTheoryPropertiesforSystemFunctionalitySwitch_3  matlab.ui.control.Switch
        FunctionalityMetricsrelatedtoGraphTheoryPanel  matlab.ui.container.Panel
        AveragedegreeCheckBox_5         matlab.ui.control.CheckBox
        CharacteristicpathlengthCheckBox_5  matlab.ui.control.CheckBox
        NetworkefficiencyCheckBox_5     matlab.ui.control.CheckBox
        ClusteringcoefficientclosedneighborhoodCheckBox_5  matlab.ui.control.CheckBox
        LocalefficiencyclosedneighborhoodCheckBox_5  matlab.ui.control.CheckBox
        ClusteringcoefficientopenneighborhoodCheckBox_5  matlab.ui.control.CheckBox
        LocalefficiencyopenneighborhoodCheckBox_5  matlab.ui.control.CheckBox
        FunctionalityMetricsnotrelatedtoGraphTheoryPanel  matlab.ui.container.Panel
        PercentageoffunctionalsubstationsbynumberCheckBox  matlab.ui.control.CheckBox
        PercentageoffunctionalsubstationbythemaximumvoltageCheckBox  matlab.ui.control.CheckBox
        PercentageofpopulationwithPowerServiceatNeighbohoodendsCheckBox  matlab.ui.control.CheckBox
        PercentageoffunctionalsubstationbythepopulationservedCheckBox  matlab.ui.control.CheckBox
        PercentageoffunctionalsubstationbythehouseholdCheckBox_3  matlab.ui.control.CheckBox
        ResiilienceMetricSelectionPanel  matlab.ui.container.Panel
        ResilienceIndexCheckBox         matlab.ui.control.CheckBox
        ResilienceLossCheckBox          matlab.ui.control.CheckBox
        RapidityCheckBox                matlab.ui.control.CheckBox
        RestorationTimeCheckBox         matlab.ui.control.CheckBox
        ResourceConstraintsPanel_3      matlab.ui.container.Panel
        CrewSpinnerLabel                matlab.ui.control.Label
        CrewSpinner                     matlab.ui.control.Spinner
        EquippedTruckSpinner_3Label     matlab.ui.control.Label
        EquippedTruckSpinner_3          matlab.ui.control.Spinner
        rdTypeofResourceLabel           matlab.ui.control.Label
        rdTypeofResourceSpinner         matlab.ui.control.Spinner
        thTypeofResourceSpinnerLabel    matlab.ui.control.Label
        thTypeofResourceSpinner         matlab.ui.control.Spinner
        CommunicationTab                matlab.ui.container.Tab
        PriorityPolicyCriterionButtonGroup_2  matlab.ui.container.ButtonGroup
        NoofhouseholdsservedbycomponentButton_2  matlab.ui.control.RadioButton
        RandomlyButton_2                matlab.ui.control.RadioButton
        PopulationservedbycomponentButton_2  matlab.ui.control.RadioButton
        InputFileforComponentsPanel_2   matlab.ui.container.Panel
        CentralOfficeCheckBox           matlab.ui.control.CheckBox
        CommunicationTowerCheckBox      matlab.ui.control.CheckBox
        MetricSelectionSystemFunctionalityPanel_2  matlab.ui.container.Panel
        GraphTheoryPropertiesforSystemFunctionalitySwitchLabel  matlab.ui.control.Label
        GraphTheoryPropertiesforSystemFunctionalitySwitch  matlab.ui.control.Switch
        FunctionalityMetricsrelatedtoGraphTheoryPanel_2  matlab.ui.container.Panel
        AveragedegreeCheckBox_4         matlab.ui.control.CheckBox
        CharacteristicpathlengthCheckBox_4  matlab.ui.control.CheckBox
        NetworkefficiencyCheckBox_4     matlab.ui.control.CheckBox
        ClusteringcoefficientclosedneighborhoodCheckBox_4  matlab.ui.control.CheckBox
        LocalefficiencyclosedneighborhoodCheckBox_4  matlab.ui.control.CheckBox
        ClusteringcoefficientopenneighborhoodCheckBox_4  matlab.ui.control.CheckBox
        LocalefficiencyopenneighborhoodCheckBox_4  matlab.ui.control.CheckBox
        FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2  matlab.ui.container.Panel
        PercentageoffunctionalcentralofficesandcommtowersCheckBox  matlab.ui.control.CheckBox
        WeightednetworkwiththeequalweightforeveryCommTowerCheckBox  matlab.ui.control.CheckBox
        PercentageofpopulationwithCommServiceatNeighbohoodendsCheckBox  matlab.ui.control.CheckBox
        WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_2  matlab.ui.control.CheckBox
        WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_3  matlab.ui.control.CheckBox
        ResiilienceMetricSelectionPanel_2  matlab.ui.container.Panel
        ResilienceIndexCheckBox_2       matlab.ui.control.CheckBox
        ResilienceLossCheckBox_2        matlab.ui.control.CheckBox
        RapidityCheckBox_2              matlab.ui.control.CheckBox
        RestorationTimeCheckBox_2       matlab.ui.control.CheckBox
        ResourceConstraintsPanel        matlab.ui.container.Panel
        CrewSpinner_2Label              matlab.ui.control.Label
        CrewSpinner_2                   matlab.ui.control.Spinner
        EquippedTruckSpinner_2Label     matlab.ui.control.Label
        EquippedTruckSpinner_2          matlab.ui.control.Spinner
        rdTypeofResourceSpinner_2Label  matlab.ui.control.Label
        rdTypeofResourceSpinner_2       matlab.ui.control.Spinner
        thTypeofResourceSpinner_2Label  matlab.ui.control.Label
        thTypeofResourceSpinner_2       matlab.ui.control.Spinner
        TransportationTab               matlab.ui.container.Tab
        PriorityPolicyCriterionButtonGroup_3  matlab.ui.container.ButtonGroup
        LengthofBridgeRoadButton        matlab.ui.control.RadioButton
        TrafficVolumeButton             matlab.ui.control.RadioButton
        RandomlyButton_3                matlab.ui.control.RadioButton
        InputFileforComponentsPanel     matlab.ui.container.Panel
        RoadNodeCheckBox                matlab.ui.control.CheckBox
        RoadLinkCheckBox                matlab.ui.control.CheckBox
        BridgeCheckBox                  matlab.ui.control.CheckBox
        TrafficLightCheckBox            matlab.ui.control.CheckBox
        RoadLinkTypeSwitchLabel         matlab.ui.control.Label
        RoadLinkTypeSwitch              matlab.ui.control.Switch
        InterdependenceTransporationDelayPanel  matlab.ui.container.Panel
        TransportDelayEffectSwitchLabel  matlab.ui.control.Label
        TransportDelayEffectSwitch      matlab.ui.control.Switch
        TaskDurationDelayCoefficientSpinnerLabel  matlab.ui.control.Label
        TaskDurationDelayCoefficientSpinner  matlab.ui.control.Spinner
        Qtran0TransportationFunctionalityThresholdLabel  matlab.ui.control.Label
        Qtran0TransportationFunctionalityThresholdSpinner  matlab.ui.control.Spinner
        belowwhichtheDelayEffectistriggeredLabel  matlab.ui.control.Label
        FunctionalityMetricSelectionPanel_2  matlab.ui.container.Panel
        FunctionalityMetricsrelatedtoGraphTheoryPanel_3  matlab.ui.container.Panel
        AveragedegreeCheckBox_3         matlab.ui.control.CheckBox
        CharacteristicpathlengthCheckBox_3  matlab.ui.control.CheckBox
        NetworkefficiencyCheckBox_3     matlab.ui.control.CheckBox
        ClusteringcoefficientclosedneighborhoodCheckBox_3  matlab.ui.control.CheckBox
        LocalefficiencyclosedneighborhoodCheckBox_3  matlab.ui.control.CheckBox
        ClusteringcoefficientopenneighborhoodCheckBox_3  matlab.ui.control.CheckBox
        LocalefficiencyopenneighborhoodCheckBox_3  matlab.ui.control.CheckBox
        FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3  matlab.ui.container.Panel
        PercentageoffunctionalroadsegmentsbynumberCheckBox  matlab.ui.control.CheckBox
        PercentageoffunctionalroadsegmentsbylengthCheckBox  matlab.ui.control.CheckBox
        PercentageoffunctionalroadsegmentsbytrafficAADTCheckBox  matlab.ui.control.CheckBox
        PercentageoffunctionalroadsegmentsbylengthtrafficCheckBox  matlab.ui.control.CheckBox
        PercendtageoffunctionalroadsegmentsbylengthCheckBox_4  matlab.ui.control.CheckBox
        GraphTheoryPropertiesforSystemFunctionalitySwitch_2Label  matlab.ui.control.Label
        GraphTheoryPropertiesforSystemFunctionalitySwitch_2  matlab.ui.control.Switch
        ResiilienceMetricSelectionPanel_3  matlab.ui.container.Panel
        ResilienceIndexCheckBox_3       matlab.ui.control.CheckBox
        ResilienceLossCheckBox_3        matlab.ui.control.CheckBox
        RapidityCheckBox_3              matlab.ui.control.CheckBox
        RestorationTimeCheckBox_3       matlab.ui.control.CheckBox
        ResourceConstraintsPanel_2      matlab.ui.container.Panel
        CrewSpinner_3Label              matlab.ui.control.Label
        CrewSpinner_3                   matlab.ui.control.Spinner
        EquippedTruckSpinnerLabel       matlab.ui.control.Label
        EquippedTruckSpinner            matlab.ui.control.Spinner
        CraneSpinnerLabel               matlab.ui.control.Label
        CraneSpinner                    matlab.ui.control.Spinner
        ConcreteMachineSpinnerLabel     matlab.ui.control.Label
        ConcreteMachineSpinner          matlab.ui.control.Spinner
        AboutTab                        matlab.ui.container.Tab
        SponsoringUniversityPanel       matlab.ui.container.Panel
        ImageLU                         matlab.ui.control.Image
        ImageFAU                        matlab.ui.control.Image
        ImageGSU                        matlab.ui.control.Image
        SponsoringAgencyPanel           matlab.ui.container.Panel
        ImageNSF                        matlab.ui.control.Image
        TextAreaNSF                     matlab.ui.control.TextArea
        CMMI1541177Button               matlab.ui.control.Button
        CMMI1541089Button               matlab.ui.control.Button
        PRAISysPanel                    matlab.ui.container.Panel
        ImagePRAISys                    matlab.ui.control.Image
        TextAreaPRAISys                 matlab.ui.control.TextArea
        LearnmoreonthePRAISyswebsiteButton  matlab.ui.control.Button
        ReadPRAISysTechnicalManualButton  matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        DoneLabel                       matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        Export % Description
    end


    methods (Access = private)
        %@tabName Tab Name: P=Power, C=Comms,T=Transp.,
        %action 1 or 0 for turning on or off
        %visibility
        function changeTabVisibilities(app,tabName,action)
            if strcmp(tabName,'P')
                if action == 0
                    app.PriorityPolicyCriterionButtonGroup.Visible='off';
                    app.InputFileforComponentsPanel_3.Visible ='off';
                    app.FunctionalityMetricSelectionPanel.Visible = 'off';
                    app.ResiilienceMetricSelectionPanel.Visible='off';
                    app.ResourceConstraintsPanel_3.Visible='off';
                else
                    app.PriorityPolicyCriterionButtonGroup.Visible='on';
                    app.InputFileforComponentsPanel_3.Visible ='on';
                    app.FunctionalityMetricSelectionPanel.Visible = 'on';
                    app.ResiilienceMetricSelectionPanel.Visible='on';
                    app.ResourceConstraintsPanel_3.Visible='on';
                    
                end
            elseif strcmp(tabName,'C')
                if action == 0
                    app.PriorityPolicyCriterionButtonGroup_2.Visible='off';
                    app.InputFileforComponentsPanel_2.Visible='off';
                    app.MetricSelectionSystemFunctionalityPanel_2.Visible='off';
                    app.ResiilienceMetricSelectionPanel_2.Visible='off';
                    app.ResourceConstraintsPanel.Visible='off';
                    
                else
                    app.PriorityPolicyCriterionButtonGroup_2.Visible='on';
                    app.InputFileforComponentsPanel_2.Visible='on';
                    app.MetricSelectionSystemFunctionalityPanel_2.Visible='on';
                    app.ResiilienceMetricSelectionPanel_2.Visible='on';
                    app.ResourceConstraintsPanel.Visible='on';
                end
            else
                if action == 0
                    app.PriorityPolicyCriterionButtonGroup_3.Visible='off';
                    app.InputFileforComponentsPanel.Visible='off';
                    app.FunctionalityMetricSelectionPanel_2.Visible='off';
                    app.ResiilienceMetricSelectionPanel_3.Visible='off';
                    app.InterdependenceTransporationDelayPanel.Visible='off';
                    app.ResourceConstraintsPanel_2.Visible='off';
                else
                    app.PriorityPolicyCriterionButtonGroup_3.Visible='on';
                    app.InputFileforComponentsPanel.Visible='on';
                    app.FunctionalityMetricSelectionPanel_2.Visible='on';
                    app.ResiilienceMetricSelectionPanel_3.Visible='on';
                    app.InterdependenceTransporationDelayPanel.Visible='on';
                    app.ResourceConstraintsPanel_2.Visible='on';
                end
                
                
            end
        end
        
        %@tabName Tab Name: P=Power, C=Comms,T=Transp.,
        %@isVisible 'on' or 'off'
        function isVisible = checkTabVisibility(app,tabName)
            isVisible='Error@checkTabVisibility';
            if strcmp(tabName,'P')
                isVisible = app.InputFileforComponentsPanel_3.Visible;
            elseif strcmp(tabName,'C')
                isVisible =  app.InputFileforComponentsPanel_2.Visible;
            else
                isVisible = app.InputFileforComponentsPanel.Visible;
            end
        end


        function changeGraphTheoryOptionsVisiblity(app,tabName,action)
            if strcmp(tabName,'T')
                if action == 1
                    app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3.Visible='off';
                    app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3.Visible='on';
                else
                    app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3.Visible='on';
                    app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3.Visible='off';
                end
            elseif strcmp(tabName,'P')
                if action == 1
                    app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel.Visible='off';
                    app.FunctionalityMetricsrelatedtoGraphTheoryPanel.Visible='on';
                else
                    app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel.Visible='on';
                    app.FunctionalityMetricsrelatedtoGraphTheoryPanel.Visible='off';
                end
            else
                if action == 1
                    app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2.Visible='off';
                    app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2.Visible='on';
                else
                    app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2.Visible='on';
                    app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2.Visible='off';
                end
            end
        end
        
        function schedulingVisiblity(app,modelType,action)
            if strcmp(modelType,'Optimization') && action ==1
                if strcmp(app.ReSchedulingOptionSwitch.Value,'ON')
                    app.ReSchedulingOptionsPanel.Visible='on';
                end
                app.OptimizationChoiceDropDown.Enable='on';
                app.OptimizationChoiceDropDownLabel.Enable='on';
                app.ReSchedulingOptionSwitchLabel.Enable='on';
                app.ReSchedulingOptionSwitch.Enable='on';
                %Run rescheduling function visiblity
            elseif strcmp(modelType,'Optimization') && action ==0
                app.OptimizationChoiceDropDown.Enable='off';
                app.OptimizationChoiceDropDownLabel.Enable='off';
                %app.ReSchedulingOptionSwitchLabel.Enable='off';
                %app.ReSchedulingOptionSwitch.Enable='off';
                %app.ReSchedulingOptionSwitchLabel.Enable='on';
                %app.ReSchedulingOptionSwitch.Enable='on';
                %Run rescheduling function visiblity
            elseif strcmp(modelType,'Priority') && action ==1      
                app.ReSchedulingOptionSwitchLabel.Visible='on';
                app.ReSchedulingOptionSwitch.Visible='on';       
                app.ReSchedulingOptionSwitchLabel.Enable='on';
                app.ReSchedulingOptionSwitch.Enable='on';       
                
                if strcmp(app.ReSchedulingOptionSwitch.Value,'ON')
                    app.ReSchedulingOptionsPanel.Visible='on';                                                
                    app.OptimizationChoiceDropDown.Visible='on';
                    app.OptimizationChoiceDropDownLabel.Visible ='on';
                    app.OptimizationChoiceDropDown.Enable='on';
                    app.OptimizationChoiceDropDownLabel.Enable='on';
                end
                %app.ReSchedulingOptionsPanel.Visible='off';
                if strcmp(checkTabVisibility(app,'P'),'on')
                    app.PriorityPolicyCriterionButtonGroup.Visible='on';
                end
                if strcmp(checkTabVisibility(app,'C'),'on')
                    app.PriorityPolicyCriterionButtonGroup_2.Visible='on';
                end
                if strcmp(checkTabVisibility(app,'P'),'on')
                    app.PriorityPolicyCriterionButtonGroup_3.Visible='on';
                end
            elseif strcmp(modelType,'Priority') && action ==0                
                %app.ReSchedulingOptionSwitchLabel.Enable='on';
                %app.ReSchedulingOptionSwitch.Enable='on';
                %app.ReSchedulingOptionsPanel.Visible='on';                
                if strcmp(checkTabVisibility(app,'P'),'on')
                    app.PriorityPolicyCriterionButtonGroup.Visible='off';
                end
                if strcmp(checkTabVisibility(app,'C'),'on')
                    app.PriorityPolicyCriterionButtonGroup_2.Visible='off';
                end
                if strcmp(checkTabVisibility(app,'P'),'on')
                    app.PriorityPolicyCriterionButtonGroup_3.Visible='off';
                end
            end
        end
        
        function reSchedulingVisibility(app,action)
            if action==1 %&& strcmp(app.RestorationPlanningSchemeSwitch.Value,'Optimization')
                app.ReSchedulingOptionsPanel.Visible='on';
                app.NoofReschedulesSpinner_2.Value=1;
                app.NoofReschedulesSpinner.Value=1;
                app.ndreschedulingtimedaySpinner.Enable='off';
                app.rdreschedulingtimedaySpinner.Enable='off';
                app.threschedulingtimedaySpinner.Enable='off';
                app.streschedulingtimedaySpinner.Enable='on';
                app.threschedulingtimedaySpinner_2.Enable='off';
                app.stSlider.Enable='on';
                app.ndSlider.Enable='off';
                app.rdSlider.Enable='off';
                app.thSlider.Enable='off';
                app.thSlider_2.Enable='off';
                app.ndreschedulingtimedaySpinnerLabel.Enable='off';
                app.rdreschedulingtimedaySpinnerLabel.Enable='off';
                app.threschedulingtimedaySpinnerLabel.Enable='off';
                app.streschedulingtimedaySpinnerLabel.Enable='off';
                app.threschedulingtimedaySpinner_2Label.Enable='off';
                app.stSliderLabel.Enable='off';
                app.ndSliderLabel.Enable='off';
                app.rdSliderLabel.Enable='off';
                app.thSliderLabel.Enable='off';
                app.thSlider_2Label.Enable='off';
            elseif action ==0 %&& strcmp(app.RestorationPlanningSchemeSwitch.Value,'Optimization')
                app.ReSchedulingOptionsPanel.Visible='off';
            end
        end
        
        
        function rescheduleNumbersVisiblity(app,type,value)
            if type==2
                if value==1
                    app.streschedulingtimedaySpinner.Enable='on';
                    app.streschedulingtimedaySpinnerLabel.Enable='on';
                    app.ndreschedulingtimedaySpinner.Enable='off';
                    app.ndreschedulingtimedaySpinnerLabel.Enable='off';
                    app.rdreschedulingtimedaySpinner.Enable='off';
                    app.rdreschedulingtimedaySpinnerLabel.Enable='off';
                    app.threschedulingtimedaySpinner.Enable='off';
                    app.threschedulingtimedaySpinnerLabel.Enable='off';
                    app.threschedulingtimedaySpinner_2.Enable='off';
                    app.threschedulingtimedaySpinner_2Label.Enable='off';
                elseif value==2
                    app.streschedulingtimedaySpinner.Enable='on';
                    app.streschedulingtimedaySpinnerLabel.Enable='on';
                    app.ndreschedulingtimedaySpinner.Enable='on';
                    app.ndreschedulingtimedaySpinnerLabel.Enable='on';
                    app.rdreschedulingtimedaySpinner.Enable='off';
                    app.rdreschedulingtimedaySpinnerLabel.Enable='off';
                    app.threschedulingtimedaySpinner.Enable='off';
                    app.threschedulingtimedaySpinnerLabel.Enable='off';
                    app.threschedulingtimedaySpinner_2.Enable='off';
                    app.threschedulingtimedaySpinner_2Label.Enable='off';
                elseif value==3
                    app.streschedulingtimedaySpinner.Enable='on';
                    app.streschedulingtimedaySpinnerLabel.Enable='on';
                    app.ndreschedulingtimedaySpinner.Enable='on';
                    app.ndreschedulingtimedaySpinnerLabel.Enable='on';
                    app.rdreschedulingtimedaySpinner.Enable='on';
                    app.rdreschedulingtimedaySpinnerLabel.Enable='on';
                    app.threschedulingtimedaySpinner.Enable='off';
                    app.threschedulingtimedaySpinnerLabel.Enable='off';
                    app.threschedulingtimedaySpinner_2.Enable='off';
                    app.threschedulingtimedaySpinner_2Label.Enable='off';
                elseif value==4
                    app.streschedulingtimedaySpinner.Enable='on';
                    app.streschedulingtimedaySpinnerLabel.Enable='on';
                    app.ndreschedulingtimedaySpinner.Enable='on';
                    app.ndreschedulingtimedaySpinnerLabel.Enable='on';
                    app.rdreschedulingtimedaySpinner.Enable='on';
                    app.rdreschedulingtimedaySpinnerLabel.Enable='on';
                    app.threschedulingtimedaySpinner.Enable='on';
                    app.threschedulingtimedaySpinnerLabel.Enable='on';
                    app.threschedulingtimedaySpinner_2.Enable='off';
                    app.threschedulingtimedaySpinner_2Label.Enable='off';
                elseif value==5
                    app.streschedulingtimedaySpinner.Enable='on';
                    app.streschedulingtimedaySpinnerLabel.Enable='on';
                    app.ndreschedulingtimedaySpinner.Enable='on';
                    app.ndreschedulingtimedaySpinnerLabel.Enable='on';
                    app.rdreschedulingtimedaySpinner.Enable='on';
                    app.rdreschedulingtimedaySpinnerLabel.Enable='on';
                    app.threschedulingtimedaySpinner.Enable='on';
                    app.threschedulingtimedaySpinnerLabel.Enable='on';
                    app.threschedulingtimedaySpinner_2.Enable='on';
                    app.threschedulingtimedaySpinner_2Label.Enable='on';
                elseif value==0
                    app.streschedulingtimedaySpinner.Enable='off';
                    app.streschedulingtimedaySpinnerLabel.Enable='off';
                    app.ndreschedulingtimedaySpinner.Enable='off';
                    app.ndreschedulingtimedaySpinnerLabel.Enable='off';
                    app.rdreschedulingtimedaySpinner.Enable='off';
                    app.rdreschedulingtimedaySpinnerLabel.Enable='off';
                    app.threschedulingtimedaySpinner.Enable='off';
                    app.threschedulingtimedaySpinnerLabel.Enable='off';
                    app.threschedulingtimedaySpinner_2.Enable='off';
                    app.threschedulingtimedaySpinner_2Label.Enable='off';
                end
                
            else
                if value==1
                    app.stSlider.Enable='on';
                    app.stSliderLabel.Enable='on';
                    app.ndSlider.Enable='off';
                    app.ndSliderLabel.Enable='off';
                    app.rdSlider.Enable='off';
                    app.rdSliderLabel.Enable='off';
                    app.thSlider.Enable='off';
                    app.thSliderLabel.Enable='off';
                    app.thSlider_2.Enable='off';
                    app.thSlider_2Label.Enable='off';
                elseif value==2
                    app.stSlider.Enable='on';
                    app.stSliderLabel.Enable='on';
                    app.ndSlider.Enable='on';
                    app.ndSliderLabel.Enable='on';
                    app.rdSlider.Enable='off';
                    app.rdSliderLabel.Enable='off';
                    app.thSlider.Enable='off';
                    app.thSliderLabel.Enable='off';
                    app.thSlider_2.Enable='off';
                    app.thSlider_2Label.Enable='off';
                elseif value==3
                    app.stSlider.Enable='on';
                    app.stSliderLabel.Enable='on';
                    app.ndSlider.Enable='on';
                    app.ndSliderLabel.Enable='on';
                    app.rdSlider.Enable='on';
                    app.rdSliderLabel.Enable='on';
                    app.thSlider.Enable='off';
                    app.thSliderLabel.Enable='off';
                    app.thSlider_2.Enable='off';
                    app.thSlider_2Label.Enable='off';
                elseif value==4
                    app.stSlider.Enable='on';
                    app.stSliderLabel.Enable='on';
                    app.ndSlider.Enable='on';
                    app.ndSliderLabel.Enable='on';
                    app.rdSlider.Enable='on';
                    app.rdSliderLabel.Enable='on';
                    app.thSlider.Enable='on';
                    app.thSliderLabel.Enable='on';
                    app.thSlider_2.Enable='off';
                    app.thSlider_2Label.Enable='off';
                elseif value==5
                    app.stSlider.Enable='on';
                    app.stSliderLabel.Enable='on';
                    app.ndSlider.Enable='on';
                    app.ndSliderLabel.Enable='on';
                    app.rdSlider.Enable='on';
                    app.rdSliderLabel.Enable='on';
                    app.thSlider.Enable='on';
                    app.thSliderLabel.Enable='on';
                    app.thSlider_2.Enable='on';
                    app.thSlider_2Label.Enable='on';
                elseif value==0
                    app.stSlider.Enable='off';
                    app.stSliderLabel.Enable='off';
                    app.ndSlider.Enable='off';
                    app.ndSliderLabel.Enable='off';
                    app.rdSlider.Enable='off';
                    app.rdSliderLabel.Enable='off';
                    app.thSlider.Enable='off';
                    app.thSliderLabel.Enable='off';
                    app.thSlider_2.Enable='off';
                    app.thSlider_2Label.Enable='off';
                end
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Export=0;
            changeTabVisibilities(app,'P',1);
            changeTabVisibilities(app,'C',1);
            changeTabVisibilities(app,'T',1);
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2.Visible='off';
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel.Visible='off';
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3.Visible='off';
            app.OptimizationChoiceDropDown.Enable='off';
            app.OptimizationChoiceDropDownLabel.Enable='off';
            %app.ReSchedulingOptionSwitch.Enable='off';
            app.ReSchedulingOptionSwitch.Enable='on';
            app.ReSchedulingOptionsPanel.Visible='off';
            %app.ReSchedulingOptionSwitchLabel.Enable='off';
            app.ReSchedulingOptionSwitchLabel.Enable='on';
            app.TaskDurationDelayCoefficientSpinner.Enable='on';
            app.TaskDurationDelayCoefficientSpinnerLabel.Enable='on';
            app.Qtran0TransportationFunctionalityThresholdSpinner.Enable='on';
            app.Qtran0TransportationFunctionalityThresholdLabel.Enable='on';
            
            
        end

        % Value changed function: PowerCheckBox
        function PowerCheckBoxValueChanged(app, event)
            value = app.PowerCheckBox.Value;
            isVisible=checkTabVisibility(app,'P');
            if strcmp('off',isVisible) & value==1
                changeTabVisibilities(app,'P',1);
            elseif strcmp('on',isVisible) & value==0
                changeTabVisibilities(app,'P',0);
                app.PowerPlantCheckBox.Value=1;
                app.SubstationCheckBox.Value=1;
                app.PowerlineCheckBox.Value=1;
            end
        end

        % Value changed function: CommunicationCheckBox
        function CommunicationCheckBoxValueChanged(app, event)
            value = app.CommunicationCheckBox.Value;
            isVisible=checkTabVisibility(app,'C');
            if strcmp('off',isVisible) && value==1
                changeTabVisibilities(app,'C',1);
            elseif strcmp('on',isVisible) && value==0
                changeTabVisibilities(app,'C',0);
            end
        end

        % Value changed function: TransportationCheckBox
        function TransportationCheckBoxValueChanged(app, event)
            value = app.TransportationCheckBox.Value;
            isVisible=checkTabVisibility(app,'T');
            if strcmp('off',isVisible) && value==1
                changeTabVisibilities(app,'T',1);
            elseif strcmp('on',isVisible) && value==0
                changeTabVisibilities(app,'T',0);
            end
        end

        % Value changed function: SamplingTypeSwitch
        function SamplingTypeSwitchValueChanged(app, event)
            value = app.SamplingTypeSwitch.Value;
            if strcmp(value,'Pseudo-Randomness')
                app.RandomSeedSpinner.Visible='on';
                app.RandomSeedSpinnerLabel.Visible='on';
                app.RandomSeedSpinner.Enable='on';
                app.RandomSeedSpinnerLabel.Enable='on';
                app.RandomSeedSpinner.FontColor='b';
                app.RandomSeedSpinnerLabel.FontColor='b';
            else
                %app.RandomSeedSpinner.Visible='off';
                %app.RandomSeedSpinnerLabel.Visible='off';
                app.RandomSeedSpinner.Enable='off';
                app.RandomSeedSpinnerLabel.Enable='off';
                app.RandomSeedSpinner.FontColor='k';
                app.RandomSeedSpinnerLabel.FontColor='k';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'ON')
                app.Switch_2.Enable='on';
                Switch_2ValueChanged(app, '')
            else
                app.Switch_2.Enable='off';
                app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner.Enable='off';
                app.AfterwardsusingthetimeunitofdayintheintermediumLabel.Enable = 'off';
                app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner.Enable='off';
                app.UsingtimeunitofhourintheimmediaterecoveryphaseuntilthedayLabel.Enable='off';
                app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner.Enable='off';
                app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinnerLabel.Enable='off';
                %app.NoteHighertimestepswillgoupto168daysor6monthsLabel.Enable='off';
            end
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value,'Non-Uniform')
                app.Switch_2.FontColor = 'b';
                app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner.Enable='on';
                app.AfterwardsusingthetimeunitofdayintheintermediumLabel.Enable = 'on';
                app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner.Enable='on';
                app.UsingtimeunitofhourintheimmediaterecoveryphaseuntilthedayLabel.Enable='on';
                app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner.Enable='on';
                app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinnerLabel.Enable='on';
                %app.NoteHighertimestepswillgoupto168daysor6monthsLabel.Enable='on';
            else
                app.Switch_2.FontColor = 'k';
                app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner.Enable='off';
                app.AfterwardsusingthetimeunitofdayintheintermediumLabel.Enable = 'off';
                app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner.Enable='off';
                app.UsingtimeunitofhourintheimmediaterecoveryphaseuntilthedayLabel.Enable='off';
                app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner.Enable='off';
                app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinnerLabel.Enable='off';
                %app.NoteHighertimestepswillgoupto168daysor6monthsLabel.Enable='off';
            end
        end

        % Value changed function: 
        % GraphTheoryPropertiesforSystemFunctionalitySwitch_2
        function GraphTheoryPropertiesforSystemFunctionalitySwitch_2ValueChanged(app, event)
            value = app.GraphTheoryPropertiesforSystemFunctionalitySwitch_2.Value;
            if strcmp(value,'OFF')
                changeGraphTheoryOptionsVisiblity(app,'T',0);
            else
                changeGraphTheoryOptionsVisiblity(app,'T',1);
            end
        end

        % Value changed function: 
        % GraphTheoryPropertiesforSystemFunctionalitySwitch_3
        function GraphTheoryPropertiesforSystemFunctionalitySwitch_3ValueChanged(app, event)
            value = app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3.Value;
            if strcmp(value,'OFF')
                changeGraphTheoryOptionsVisiblity(app,'P',0);
            else
                changeGraphTheoryOptionsVisiblity(app,'P',1);
            end
        end

        % Value changed function: 
        % GraphTheoryPropertiesforSystemFunctionalitySwitch
        function GraphTheoryPropertiesforSystemFunctionalitySwitchValueChanged(app, event)
            value = app.GraphTheoryPropertiesforSystemFunctionalitySwitch.Value;
            if strcmp(value,'OFF')
                changeGraphTheoryOptionsVisiblity(app,'C',0);
            else
                changeGraphTheoryOptionsVisiblity(app,'C',1);
            end
        end

        % Value changed function: RestorationPlanningSchemeSwitch
        function RestorationPlanningSchemeSwitchValueChanged(app, event)
            value = app.RestorationPlanningSchemeSwitch.Value;
            if strcmp(value,'Priority')
                schedulingVisiblity(app,'Priority',1);
                %schedulingVisiblity(app,'Optimization',0);
            else
                schedulingVisiblity(app,'Priority',0);
                schedulingVisiblity(app,'Optimization',1);
            end
        end

        % Value changed function: ReSchedulingOptionSwitch
        function ReSchedulingOptionSwitchValueChanged(app, event)
            value = app.ReSchedulingOptionSwitch.Value;
            if strcmp(value,'OFF')
                reSchedulingVisibility(app,0);
                app.OptimizationChoiceDropDownLabel.Visible = 'off';
                app.OptimizationChoiceDropDown.Visible = 'off';
                
                app.OptimizationChoiceDropDownLabel.Enable = 'off';
                app.OptimizationChoiceDropDown.Enable = 'off';
            else
                reSchedulingVisibility(app,1);
                app.OptimizationChoiceDropDownLabel.Visible = 'on';
                app.OptimizationChoiceDropDown.Visible = 'on';
                
                app.OptimizationChoiceDropDownLabel.Enable = 'on';
                app.OptimizationChoiceDropDown.Enable = 'on';
            end
        end

        % Value changed function: NoofReschedulesSpinner_2
        function NoofReschedulesSpinner_2ValueChanged(app, event)
            value = app.NoofReschedulesSpinner_2.Value;
            rescheduleNumbersVisiblity(app,1,value)
        end

        % Value changed function: NoofReschedulesSpinner
        function NoofReschedulesSpinnerValueChanged(app, event)
            value = app.NoofReschedulesSpinner.Value;
            rescheduleNumbersVisiblity(app,2,value)
        end

        % Value changed function: TransportDelayEffectSwitch
        function TransportDelayEffectSwitchValueChanged(app, event)
            value = app.TransportDelayEffectSwitch.Value;
            if strcmp(value,'ON')
                app.TaskDurationDelayCoefficientSpinner.Enable='on';
                app.TaskDurationDelayCoefficientSpinnerLabel.Enable='on';
                app.Qtran0TransportationFunctionalityThresholdSpinner.Enable='on';
                app.Qtran0TransportationFunctionalityThresholdLabel.Enable='on';
            else
                app.TaskDurationDelayCoefficientSpinner.Enable='off';
                app.TaskDurationDelayCoefficientSpinnerLabel.Enable='off';
                app.Qtran0TransportationFunctionalityThresholdSpinner.Enable='off';
                app.Qtran0TransportationFunctionalityThresholdLabel.Enable='off';
            end
        end

        % Value changed function: RoadLinkCheckBox
        function RoadLinkCheckBoxValueChanged(app, event)
            value = app.RoadLinkCheckBox.Value;
            if value==1
                app.RoadLinkTypeSwitch.Enable='on';
                app.RoadLinkTypeSwitchLabel.Enable='on';
            else
                app.RoadLinkTypeSwitch.Enable='off';
                app.RoadLinkTypeSwitchLabel.Enable='off';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % set up the default value for the StopButton 
            %set(app.StopButton, 'Value', 0);
            cd(uigetdir)
            % Write the input data into the file named "Input.txt" 
            fileID=fopen("Input.txt",'w');
            hazardMap=app.HazardMapDropDown.Value;%% file name of the hazard map
            
            %%%==== Original expression
            %if strcmp(hazardMap,'Hazard_ShakeMap_M5_1000year')
            %    hazardType=1; 
            %elseif strcmp(hazardMap,'Hazard_ShakeMap_M5_2500year_LVvirtualLargerArea')
            %    hazardType=1;
            %elseif strcmp(hazardMap,'Hazard_HurricaneSandy20121030')
            %    hazardType=2;
            %end
            
            %%%==== Revised expression
            if contains(hazardMap, 'Hazard_ShakeMap') 
                hazardType = 1;
            elseif contains(hazardMap, 'Hazard_Hurricane') 
                hazardType = 2;
            end
            
            fprintf(fileID,'#----- Hazard Map Selection (1-earthquake, 2-hurricane, 3-flood)\n');
            fprintf(fileID,'%s %d %s.mat\n','HazardMap',hazardType,app.HazardMapDropDown.Value);
            fprintf(fileID,'\n#----- Active System Selection (0/1)\n');
            
            powerString='';
            communicationString='';
            transportationString='';
            if(app.PowerPlantCheckBox.Value ==1)
                powerString=strcat(powerString,' PowerPlants');
                %powerString=strcat(powerString,' PowerPlant');
            end
            if(app.SubstationCheckBox.Value==1)
                powerString=strcat(powerString,' Substations');
                %powerString=strcat(powerString,' Substation');
            end
            if(app.PowerlineCheckBox.Value==1)
                powerString=strcat(powerString,' Power_Line');
                %powerString=strcat(powerString,' PowerLine');
            end
            if(app.CentralOfficeCheckBox.Value==1)
                communicationString=strcat(communicationString,' CentralOffices');
                %communicationString=strcat(communicationString,' CentralOffice');
            end
            if(app.CommunicationTowerCheckBox.Value==1)
                communicationString=strcat(communicationString,' CommunicationTowers');
                %communicationString=strcat(communicationString,' CommunicationTower');
            end
            if(app.RoadNodeCheckBox.Value==1)
                transportationString=strcat(transportationString,' RoadNode');
            end
            if(app.RoadLinkCheckBox.Value==1)
                transportationString=strcat(transportationString,' RoadLink');
            end
            if(app.BridgeCheckBox.Value==1)
                transportationString=strcat(transportationString,' Bridges');
                %transportationString=strcat(transportationString,' Bridge');
            end
            if(app.TrafficLightCheckBox.Value==1)
               transportationString=strcat(transportationString,' TrafficLights');
               %transportationString=strcat(transportationString,' TrafficLight');
            end
            fprintf(fileID,['Power %d%s\nCommunication %d%s\nTransportation %d%s\n'], ...
                app.PowerCheckBox.Value,powerString,...
                app.CommunicationCheckBox.Value,communicationString,...
                app.TransportationCheckBox.Value,transportationString);
            if strcmp(app.RoadLinkTypeSwitch.Value,'Directional')
                roadLinkType=1;
            else
                roadLinkType=0;
            end
            fprintf(fileID,'\n#----- Direction of road link (1-directional, 0-not directional)\n');
            fprintf(fileID,'TransportationLinkDirection %d \n',roadLinkType);
            fprintf(fileID,'\n#----- Set up the input data of neighborhood community\n');
            fprintf(fileID,'DataNeighbor %s\n',app.InputDataDropDown.Value);
            fprintf(fileID,'\n#----- Turn ON(1)/OFF(0) the Sub-Component Analysis\n');
            
            subAnalysis=0;
            if strcmp(app.SubComponentAnalysisSwitch.Value,'ON')
                subAnalysis=1;
            end
            fprintf(fileID,'SubObject %d\n',subAnalysis);
            
            timeHorizon=app.TimeHorizonDaySpinner.Value;
            fprintf(fileID,'\n#----- Choose time_horizon\n');
            fprintf(fileID,'TimeHorizon %d\n', timeHorizon);
            numDamageScenarios=app.NoofDamageScenarioSamplesSpinner.Value;
            numDamageSampler=app.NoofTaskDurationSampleperDamageSampleSpinner.Value;
            fprintf(fileID,'\n#----- Number of Damage Scenario Sample (Nsamples>0) and Number of Task Duration Sample per Damage Sample (NRun>0)\n');
            fprintf(fileID,'Nsamples %d\nNRun %d\n', numDamageScenarios,numDamageSampler);
            samplingType=0;
            if strcmp(app.SamplingTypeSwitch.Value,'True-Random Sampling')
                samplingType=1;
            end
            fprintf(fileID,'\n#----- Turn ON(1)/OFF(0) True Random Sampling (1: Yes, using True-Random; 0: No, using Pseudo-Random instead, followed by a Random Seed when pseudo-randomness is turned on.)\n');
            fprintf(fileID,'RandomChoice %d %d\n', samplingType,app.RandomSeedSpinner.Value);
            fprintf(fileID,'\n#----- Profile Selection for the Parallel Computing\n');
            fprintf(fileID,'Profile_Num %d\n', app.ParallelComputingSpinner.Value);
            fprintf(fileID,'\n#----- Select a Scheduler Model to Develop the Restoration Plan:\n# Scheduler = 1-Priority,  Scheduler = 2-Optimization;\n# ReSchedule = 0(Turn ON Rescheduling), ReSchedule = 1(Turn OFF Rescheduling); Rescheduling uses the optimization scheduling scheme based on the OptimizationChoice.\n# OptimizationChoice = 1-OptimizationRCPCP_FT, 2-OptimizationRCPCP_ST, 3-OptimizationFlexible, 4-SubOptimizationRCPCP_FT, 5-SubOptimizationRCPCP_ST\n');
            if strcmp(app.RestorationPlanningSchemeSwitch.Value, 'Priority')
                fprintf(fileID,'Scheduler %d\n', 1);
                if strcmp(app.ReSchedulingOptionSwitch.Value, 'OFF')
                    fprintf(fileID,'ReSchedule %d\n', 0);
                    fprintf(fileID,'OptimizationChoice \n');
                else
                    fprintf(fileID,'ReSchedule %d\n', 1);
                    if strcmp(app.OptimizationChoiceDropDown.Value,'OptimizationRCPCP_FT')
                        fprintf(fileID,'OptimizationChoice %d\n', 1);
                    elseif strcmp(app.OptimizationChoiceDropDown.Value,'OptimizationRCPCP_ST')
                        fprintf(fileID,'OptimizationChoice %d\n', 2);
                    elseif strcmp(app.OptimizationChoiceDropDown.Value,'OptimizationFlexible')
                        fprintf(fileID,'OptimizationChoice %d\n', 3);
                    elseif strcmp(app.OptimizationChoiceDropDown.Value,'SubOptimizationRCPCP_FT')
                        fprintf(fileID,'OptimizationChoice %d\n', 4);
                    elseif strcmp(app.OptimizationChoiceDropDown.Value,'SubOptimizationRCPCP_ST')
                        fprintf(fileID,'OptimizationChoice %d\n', 5);
                    end
                end
                
            else
                fprintf(fileID,'Scheduler %d\n', 2);
                if strcmp(app.ReSchedulingOptionSwitch.Value, 'OFF')
                    fprintf(fileID,'ReSchedule %d\n', 0);
                else
                    fprintf(fileID,'ReSchedule %d\n', 1);
                end
                if strcmp(app.OptimizationChoiceDropDown.Value,'OptimizationRCPCP_FT')
                    fprintf(fileID,'OptimizationChoice %d\n', 1);
                elseif strcmp(app.OptimizationChoiceDropDown.Value,'OptimizationRCPCP_ST')
                    fprintf(fileID,'OptimizationChoice %d\n', 2);
                elseif strcmp(app.OptimizationChoiceDropDown.Value,'OptimizationFlexible')
                    fprintf(fileID,'OptimizationChoice %d\n', 3);
                elseif strcmp(app.OptimizationChoiceDropDown.Value,'SubOptimizationRCPCP_FT')
                    fprintf(fileID,'OptimizationChoice %d\n', 4);
                elseif strcmp(app.OptimizationChoiceDropDown.Value,'SubOptimizationRCPCP_ST')
                    fprintf(fileID,'OptimizationChoice %d\n', 5);
                end
            end
            if strcmp(app.RestorationPlanningSchemeSwitch.Value, 'Priority')
                fprintf(fileID,'\n# Set up the Prioity Policy Criterion for Every System if the above Scheduler = 1:\n# priority_power: 0 for randomly, 1 for voltage, 2 for population served at component-level, 3 for number of household served at component-level \n# priority_communciation: 0 for randomly, 1 for population served at component-level, 2 for number of household served at component-level \n# priority_transportation: 0 for randomly, 1 for length, 2 for traffic volume\n');

% %% Updated codes by considering more priority options                
                powerNum=1;
                if (app.RandomlyButton.Value == 1)
                    powerNum=0;
                elseif (app.VoltageButton.Value == 1)
                    powerNum=1;
                elseif (app.PopulationservedbycomponentButton.Value == 1)
                    powerNum=2;
                elseif (app.NoofhouseholdsservedbycomponentButton.Value == 1)
                    powerNum=3;
                end
                
                communicationNum=1;
                if (app.RandomlyButton_2.Value == 1)
                    communicationNum=0;
                elseif (app.PopulationservedbycomponentButton_2.Value == 1)
                    communicationNum=1;
                elseif (app.NoofhouseholdsservedbycomponentButton_2.Value == 1)
                    communicationNum=2;
                end
                
                transportationNum=1;
                if (app.RandomlyButton_3.Value == 1)
                    transportationNum=0;
                elseif (app.TrafficVolumeButton.Value == 1)
                    transportationNum=1;
                elseif (app.LengthofBridgeRoadButton.Value == 1)
                    transportationNum=2;
                end        
                
                if(app.PowerCheckBox.Value==1)
                    fprintf(fileID,'Priority_power_num %d\n',powerNum);
                end
                if(app.CommunicationCheckBox.Value==1)
                    fprintf(fileID,'Priority_communication_num %d\n',communicationNum);
                end
                if(app.TransportationCheckBox.Value==1)
                    fprintf(fileID,'Priority_transportation_num %d\n',transportationNum);
                end
                
            end
            
            %if (strcmp(app.RestorationPlanningSchemeSwitch.Value,'Optimization') && strcmp(app.ReSchedulingOptionSwitch.Value,'ON'))
            if strcmp(app.ReSchedulingOptionSwitch.Value,'ON')
                fprintf(fileID,'\n#----- Rescheduling setting\n# Percentage of completed tasks for rescheduling, can be multiple numbers (0-1)\n# Num_stage: Time to reschedule (in days), can be multiple integer numbers (0,time_horizon)\n');
                numberPercentString="";
                if (app.stSlider.Value ~= 0 && app.NoofReschedulesSpinner_2.Value>=1)
                    numberPercentString =strcat(numberPercentString," ",num2str((app.stSlider.Value)/100,2));
                end
                if (app.ndSlider.Value ~= 0 && app.NoofReschedulesSpinner_2.Value>=2)
                    numberPercentString =strcat(numberPercentString," ",num2str((app.ndSlider.Value)/100,2));
                end
                if (app.rdSlider.Value ~= 0 && app.NoofReschedulesSpinner_2.Value>=3)
                    numberPercentString =strcat(numberPercentString," ",num2str((app.rdSlider.Value)/100,2));
                end
                if (app.thSlider.Value ~= 0 && app.NoofReschedulesSpinner_2.Value>=4)
                    numberPercentString =strcat(numberPercentString," ",num2str((app.thSlider.Value)/100,2));
                end
                if (app.thSlider_2.Value ~= 0 && app.NoofReschedulesSpinner_2.Value>=5)
                    numberPercentString =strcat(numberPercentString," ",num2str((app.thSlider_2.Value)/100,2));
                end
                fprintf(fileID,'RescheduleAtPercentage%s\n',numberPercentString');
                numberTimeString="";
                if (app.streschedulingtimedaySpinner.Value ~= 0 && app.NoofReschedulesSpinner.Value>=1)
                    numberTimeString =strcat(numberTimeString," ",num2str((app.streschedulingtimedaySpinner.Value)));
                end
                if (app.ndreschedulingtimedaySpinner.Value ~= 0 && app.NoofReschedulesSpinner.Value>=2)
                    numberTimeString =strcat(numberTimeString," ",num2str((app.ndreschedulingtimedaySpinner.Value)));
                end
                if (app.rdreschedulingtimedaySpinner.Value ~= 0 && app.NoofReschedulesSpinner.Value>=3)
                    numberTimeString =strcat(numberTimeString," ",num2str((app.rdreschedulingtimedaySpinner.Value)));
                end
                if (app.threschedulingtimedaySpinner.Value ~= 0 && app.NoofReschedulesSpinner.Value>=4)
                    numberTimeString =strcat(numberTimeString," ",num2str((app.threschedulingtimedaySpinner.Value)));
                end
                if (app.threschedulingtimedaySpinner_2.Value ~= 0 && app.NoofReschedulesSpinner.Value>=5)
                    numberTimeString =strcat(numberTimeString," ",num2str((app.threschedulingtimedaySpinner_2.Value)));
                end
                fprintf(fileID,'RescheduleAtTime%s\n',numberTimeString');
            else
                fprintf(fileID,'\n#----- Rescheduling setting\n# Percentage of completed tasks for rescheduling, can be multiple numbers (0-1)\n# Num_stage: Time to reschedule (in days), can be multiple integer numbers (0,time_horizon)\n');
                fprintf(fileID,'RescheduleAtPercentage \n');
                fprintf(fileID,'RescheduleAtTime \n');
            end
            
            if(strcmp(app.Switch.Value,'ON') && strcmp(app.Switch_2.Value,'Non-Uniform'))
                fprintf(fileID,'\n#----- Different time unit in Recovery(0: the default time unit of "day"; 1: the customized time unit, [hour, day, week, month])\nDifferentTimeUnit 1\n')
                fprintf(fileID,'RestorationPhaseDay %d %d %d\n',app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner.Value,app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner.Value,app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner.Value);
            else
                fprintf(fileID,'\n#----- The same time unit in Recovery(0: the default time unit of "day"; 1: the customized time unit, [hour, day, week, month])\nDifferentTimeUnit 0\n')
                fprintf(fileID,'RestorationPhaseDay %d %d %d\n',app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner.Value,app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner.Value,app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner.Value);
            end
            fprintf(fileID,'\n#----- Set up the users choices of decision strategies as different interdependency combinations\n#-- Turn on(1)/off(0) the Combined System Scheduling: 1=seperately (scheduling restoration tasks for every indiviual system), 0=together (scheduling restoration tasks for all systems together)\n');
            if(strcmp(app.CoordinaterestorationplanningacrosssystemsSwitch.Value,'OFF'))
                fprintf(fileID,'Seperate_Scheduling 1\n');
            else
                fprintf(fileID,'Seperate_Scheduling 0\n');
            end
            fprintf(fileID,'\n#-- Turn on(1)/off(0) transport delaying factor due to major transportation functionality disruption (if turn on, the following 2 numbers are a.the delaying coefficient to multiply the task duration, b.Qtran0-the transportation functionality threshold, delaying in effect is Qtrans<Qtrans0)\n');
            if (strcmp(app.TransportDelayEffectSwitch.Value,'ON'))
                fprintf(fileID,'InterdependenceTransDelay 1 %s %s\n',num2str((app.TaskDurationDelayCoefficientSpinner.Value),2),num2str((app.Qtran0TransportationFunctionalityThresholdSpinner.Value),2));
            else
                fprintf(fileID,'InterdependenceTransDelay 0\n');
            end
            fprintf(fileID,'\n#-- Turn on(1)/off(0) inter-system functionality dependency\n');
            if (strcmp(app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch.Value,'OFF'))
                fprintf(fileID,'InterdependenceFunctionality 1\n');
            else
                fprintf(fileID,'InterdependenceFunctionality 0\n');
            end
            fprintf(fileID,'\n#-- Turn on(1)/off(0) precedence dependency \n');
            if (strcmp(app.RelaxprecendenceconstraintbetweensometasksSwitch.Value,'OFF'))
                fprintf(fileID,'InterdependencePrecedence 1\n');
            else
                fprintf(fileID,'InterdependencePrecedence 0\n');
            end
            
            
            %----- Metric Selection
            fprintf(fileID,'\n#----- Metric Selection for System Functionality and Resilience\n# Functionality_Power = 1-Percentage of open substations, 2-Weighted network as substation voltage, 3-Percentage of population with Power Service at Neighbohood ends, OR properties from graph theory, including 4-Average degree, 5-Characteristic path length, 6-Network efficiency, 7-Clustering coefficient (closed neighborhood), 8-Local efficiency (closed neighborhood), 9-Clustering coefficient (open neighborhood), 10-Local efficiency (open neighborhood)\n# Functionality_Comm = 1-Percentage of open CommTower, 2-Weighted network with the equal weight of every CommTower, 3-Percentage of population with Comm Service at Neighbohood ends, OR properties from graph theory, including 4-Average degree, 5-Characteristic path length, Network efficiency, 7-Clustering coefficient (closed neighborhood), 8-Local efficiency (closed neighborhood), -9Clustering coefficient (open neighborhood), 10-Local efficiency (open neighborhood)\n# Functionality_Trans = 1-Percentage of open road segments, 2-Weighted network as the road (1)length/or(2)AADT/or(3)length*AADT, 3-Percentage of population with the road access at Neighbohood-level, OR properties from graph theory, including 4-Average degree, 5-Characteristic path length, 6-Network efficiency, 7-Clustering coefficient (closed neighborhood), 8-Local efficiency (closed neighborhood), 9-Clustering coefficient (open neighborhood), 10-Local efficiency (open neighborhood)  \n# Resilience Metric: 1-Resilience index, 2-Resilience loss, 3-Rapidity, 4-Restoration time\n');
            if(app.PowerCheckBox.Value==1)
                powerFuncString='';
                if(strcmp(app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3.Value,'ON'))
                    if(app.AveragedegreeCheckBox_5.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'6');
                    end
                    if(app.CharacteristicpathlengthCheckBox_5.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'7');
                    end
                    if(app.NetworkefficiencyCheckBox_5.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'8');
                    end
                    if(app.ClusteringcoefficientclosedneighborhoodCheckBox_5.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'9');
                    end
                    if(app.LocalefficiencyclosedneighborhoodCheckBox_5.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'10');
                    end
%                     if(app.ClusteringcoefficientopenneighborhoodCheckBox_5.Value==1)
%                         powerFuncString=strcat(powerFuncString," ",'9');
%                     end
%                     if(app.LocalefficiencyopenneighborhoodCheckBox_5.Value==1)
%                         powerFuncString=strcat(powerFuncString," ",'10');
%                     end
                    
                else
                    if(app.PercentageoffunctionalsubstationsbynumberCheckBox.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'1');
                    end
                    if(app.PercentageoffunctionalsubstationbythemaximumvoltageCheckBox.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'2');
                    end
                    if(app.PercentageoffunctionalsubstationbythepopulationservedCheckBox.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'3');
                    end
                    if(app.PercentageoffunctionalsubstationbythehouseholdCheckBox_3.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'4');
                    end
                    if(app.PercentageofpopulationwithPowerServiceatNeighbohoodendsCheckBox.Value==1)
                        powerFuncString=strcat(powerFuncString," ",'5');
                    end
                end
                fprintf(fileID,'Functionality_Power%s\n',powerFuncString);
            end
            
            if(app.CommunicationCheckBox.Value==1)
                commFuncString='';
                if(strcmp(app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3.Value,'ON'))
                    if(app.AveragedegreeCheckBox_4.Value==1)
                        commFuncString=strcat(commFuncString," ",'6');
                    end
                    if(app.CharacteristicpathlengthCheckBox_4.Value==1)
                        commFuncString=strcat(commFuncString," ",'7');
                    end
                    if(app.NetworkefficiencyCheckBox_4.Value==1)
                        commFuncString=strcat(commFuncString," ",'8');
                    end
                    if(app.ClusteringcoefficientclosedneighborhoodCheckBox_4.Value==1)
                        commFuncString=strcat(commFuncString," ",'9');
                    end
                    if(app.LocalefficiencyclosedneighborhoodCheckBox_4.Value==1)
                        commFuncString=strcat(commFuncString," ",'10');
                    end
%                     if(app.ClusteringcoefficientopenneighborhoodCheckBox_4.Value==1)
%                         commFuncString=strcat(commFuncString," ",'9');
%                     end
%                     if(app.LocalefficiencyopenneighborhoodCheckBox_4.Value==1)
%                         commFuncString=strcat(commFuncString," ",'10');
%                     end
                    
                else
                    if(app.PercentageoffunctionalcentralofficesandcommtowersCheckBox.Value==1)
                        commFuncString=strcat(commFuncString," ",'1');
                    end
                    if(app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox.Value==1)
                        commFuncString=strcat(commFuncString," ",'2');
                    end
                    if(app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_2.Value==1)
                        commFuncString=strcat(commFuncString," ",'3');
                    end
                    if(app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_3.Value==1)
                        commFuncString=strcat(commFuncString," ",'4');
                    end
                    if(app.PercentageofpopulationwithCommServiceatNeighbohoodendsCheckBox.Value==1)
                        commFuncString=strcat(commFuncString," ",'5');
                    end
                end
                fprintf(fileID,'Functionality_Comm%s\n',commFuncString);
            end
            
            if(app.TransportationCheckBox.Value==1)
                transFuncString='';
                if(strcmp(app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3.Value,'ON'))
                    if(app.AveragedegreeCheckBox_3.Value==1)
                        transFuncString=strcat(transFuncString," ",'6');
                    end
                    if(app.CharacteristicpathlengthCheckBox_3.Value==1)
                        transFuncString=strcat(transFuncString," ",'7');
                    end
                    if(app.NetworkefficiencyCheckBox_3.Value==1)
                        transFuncString=strcat(transFuncString," ",'8');
                    end
                    if(app.ClusteringcoefficientclosedneighborhoodCheckBox_3.Value==1)
                        transFuncString=strcat(transFuncString," ",'9');
                    end
                    if(app.LocalefficiencyclosedneighborhoodCheckBox_3.Value==1)
                        transFuncString=strcat(transFuncString," ",'10');
                    end
%                     if(app.ClusteringcoefficientopenneighborhoodCheckBox_3.Value==1)
%                         transFuncString=strcat(transFuncString," ",'9');
%                     end
%                     if(app.LocalefficiencyopenneighborhoodCheckBox_3.Value==1)
%                         transFuncString=strcat(transFuncString," ",'10');
%                     end
                else
                    if(app.PercentageoffunctionalroadsegmentsbynumberCheckBox.Value==1)
                        transFuncString=strcat(transFuncString," ",'1');
                    end
                    if(app.PercentageoffunctionalroadsegmentsbylengthCheckBox.Value==1)
                        transFuncString=strcat(transFuncString," ",'2');
                    end
                    if(app.PercentageoffunctionalroadsegmentsbytrafficAADTCheckBox.Value==1)
                        transFuncString=strcat(transFuncString," ",'3');
                    end
                    if(app.PercentageoffunctionalroadsegmentsbylengthtrafficCheckBox.Value==1)
                        transFuncString=strcat(transFuncString," ",'4');
                    end
                    if(app.PercendtageoffunctionalroadsegmentsbylengthCheckBox_4.Value==1)
                        transFuncString=strcat(transFuncString," ",'5');
                    end
                end
                fprintf(fileID,'Functionality_Trans%s\n',transFuncString);
            end
            
            if(app.PowerCheckBox.Value==1)
                powerResString='';
                if(app.ResilienceIndexCheckBox.Value==1)
                    powerResString=strcat(powerResString," ",'1');
                end
                if(app.ResilienceLossCheckBox.Value==1)
                    powerResString=strcat(powerResString," ",'2');
                end
                if(app.RapidityCheckBox.Value==1)
                    powerResString=strcat(powerResString," ",'3');
                end
                if(app.RestorationTimeCheckBox.Value==1)
                    powerResString=strcat(powerResString," ",'4');
                end
                fprintf(fileID,'Resilience_Power%s\n',powerResString);
            end
            
            if(app.CommunicationCheckBox.Value==1)
                commResString='';
                if(app.ResilienceIndexCheckBox_2.Value==1)
                    commResString=strcat(commResString," ",'1');
                end
                if(app.ResilienceLossCheckBox_2.Value==1)
                    commResString=strcat(commResString," ",'2');
                end
                if(app.RapidityCheckBox_2.Value==1)
                    commResString=strcat(commResString," ",'3');
                end
                if(app.RestorationTimeCheckBox_2.Value==1)
                    commResString=strcat(commResString," ",'4');
                end
                fprintf(fileID,'Resilience_Comm%s\n',commResString);
                
            end
            
            if(app.TransportationCheckBox.Value==1)
                transResString='';
                if(app.ResilienceIndexCheckBox_3.Value==1)
                    transResString=strcat(transResString," ",'1');
                end
                if(app.ResilienceLossCheckBox_3.Value==1)
                    transResString=strcat(transResString," ",'2');
                end
                if(app.RapidityCheckBox_3.Value==1)
                    transResString=strcat(transResString," ",'3');
                end
                if(app.RestorationTimeCheckBox_3.Value==1)
                    transResString=strcat(transResString," ",'4');
                end
                fprintf(fileID,'Resilience_Trans%s\n',transResString);
            end
            
            fprintf(fileID,'\n# Resource Constraints for Every System\n');
            fprintf(fileID,'Power_Resource %d %d %d %d\n',app.CrewSpinner.Value,app.EquippedTruckSpinner_3.Value,app.rdTypeofResourceSpinner.Value,app.thTypeofResourceSpinner.Value);
            fprintf(fileID,'Comm_Resource %d %d %d %d\n',app.CrewSpinner_2.Value,app.EquippedTruckSpinner_2.Value,app.rdTypeofResourceSpinner_2.Value,app.thTypeofResourceSpinner_2.Value);
            fprintf(fileID,'Trans_Resource %d %d %d %d\n',app.CrewSpinner_3.Value,app.EquippedTruckSpinner.Value,app.CraneSpinner.Value,app.ConcreteMachineSpinner.Value);
            fprintf(fileID,'\n# Probability of the presence of a Magic Battery for every component that originally uses the electricity from the Power system\n');
            fprintf(fileID,'Prob_Magic_Battery %s\n',num2str((app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Value)/100,2));
            fprintf(fileID,'\n # Save all history results\n');
            if(strcmp(app.Switch_3.Value,"NO"))
                fprintf(fileID,'Save_history 0\n');
            else
                fprintf(fileID,'Save_history 1\n');
            end
            app.ImportedLabel.Visible='off';
            app.ExportedLabel.Visible='off';
            app.DoneLabel.Visible='on';
            %==== Run the main file of the PRAISys platform
            %global stopVariable;
            %stopVariable = false;
            %stopVariable = true;
            cont = 1;
            if (app.Export==0)
                %=== attemp for allowing the user to stop the main function by clicking the Stop button, 
                % Once the Stop button is clicked, StopButton.Enable changes
                % from the default value of "on" to "off"
                main(app);                   
            end
            
           
        end

        % Button pushed function: ImportSettingsButton
        function ImportSettingsButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            delete "Input.txt";
            copyfile(path,"Input.txt", 'f');
            app.ImportedLabel.Visible='on';
            app.RunButtonPushed(app);
            
        end

        % Button pushed function: ExportSettingsButton
        function ExportSettingsButtonPushed(app, event)
            [file,path,indx] = uiputfile("Input.txt");
            app.Export=1;
            app.RunButtonPushed(app);
            copyfile("Input.txt",path,'f');
            app.DoneLabel.Visible='off';
            app.ExportedLabel.Visible='on';
            app.Export=0;
        end

        % Value changed function: TimeHorizonDaySpinner
        function TimeHorizonDaySpinnerValueChanged(app, event)
            Th = app.TimeHorizonDaySpinner.Value;
            app.streschedulingtimedaySpinner.Limits = [1,Th];
            app.ndreschedulingtimedaySpinner.Limits = [1,Th];
            app.rdreschedulingtimedaySpinner.Limits = [1,Th];
            app.threschedulingtimedaySpinner.Limits = [1,Th];
            app.threschedulingtimedaySpinner_2.Limits = [1,Th];
        end

        % Value changed function: streschedulingtimedaySpinner
        function streschedulingtimedaySpinnerValueChanged(app, event)
            Th = app.TimeHorizonDaySpinner.Value;
            Tr1 = app.streschedulingtimedaySpinner.Value;
            if ge(Th,Tr1)
                app.ndreschedulingtimedaySpinner.Limits = [1 + Tr1,Th];
                app.rdreschedulingtimedaySpinner.Limits = [1 + Tr1,Th];
                app.threschedulingtimedaySpinner.Limits = [1 + Tr1,Th];
                app.threschedulingtimedaySpinner_2.Limits = [1 + Tr1,Th];
            else
                ferror = uifigure('Position',[100 150 450 250]);
                uialert(ferror,'The 1st rescheduling time should be a positive integer no greater than time horizon','Invalid rescheduling time');
            end
            
        end

        % Value changed function: ndreschedulingtimedaySpinner
        function ndreschedulingtimedaySpinnerValueChanged(app, event)
            Th = app.TimeHorizonDaySpinner.Value;
            Tr2 = app.ndreschedulingtimedaySpinner.Value;
            if ge(Th,Tr2)
                app.rdreschedulingtimedaySpinner.Limits = [1 + Tr2,Th];
                app.threschedulingtimedaySpinner.Limits = [1 + Tr2,Th];
                app.threschedulingtimedaySpinner_2.Limits = [1 + Tr2,Th];
            else
                ferror = uifigure('Position',[100 150 450 250]);
                uialert(ferror,'The 2nd rescheduling time should be a positive integer greater than the 1st rescheduling time and no greater than time horizon','Invalid rescheduling time');
            end
        end

        % Value changed function: rdreschedulingtimedaySpinner
        function rdreschedulingtimedaySpinnerValueChanged(app, event)
            Th = app.TimeHorizonDaySpinner.Value;
            Tr3 = app.rdreschedulingtimedaySpinner.Value;
            if ge(Th,Tr3)
                app.threschedulingtimedaySpinner.Limits = [1 + Tr3,Th];
                app.threschedulingtimedaySpinner_2.Limits = [1 + Tr3,Th];
            else
                ferror = uifigure('Position',[100 150 450 250]);
                uialert(ferror,'The 3rd rescheduling time should be a positive integer greater than the 2nd rescheduling time and no greater than time horizon','Invalid rescheduling time');
            end
        end

        % Value changed function: threschedulingtimedaySpinner
        function threschedulingtimedaySpinnerValueChanged(app, event)
            Th = app.TimeHorizonDaySpinner.Value;
            Tr4 = app.threschedulingtimedaySpinner.Value;
            if ge(Th,Tr4)
                app.threschedulingtimedaySpinner_2.Limits = [1 + Tr4,Th];
            else
                ferror = uifigure('Position',[100 150 450 250]);
                uialert(ferror,'The 4th rescheduling time should be a positive integer greater than the 3rd rescheduling time and no greater than time horizon','Invalid rescheduling time');
            end
        end

        % Value changed function: stSlider
        function stSliderValueChanged(app, event)
            Qthreshold1 = app.stSlider.Value;
            app.ndSlider.Limits = [1+Qthreshold1,100];
            app.rdSlider.Limits = [1+Qthreshold1,100];
            app.thSlider.Limits = [1+Qthreshold1,100];
            app.thSlider_2.Limits = [1+Qthreshold1,100];
        end

        % Value changed function: ndSlider
        function ndSliderValueChanged(app, event)
            Qthreshold1 = app.stSlider.Value;
            Qthreshold2 = app.ndSlider.Value;
            if gt(Qthreshold2, Qthreshold1)
                app.rdSlider.Limits = [1+Qthreshold2,100];
                app.thSlider.Limits = [1+Qthreshold2,100];
                app.thSlider_2.Limits = [1+Qthreshold2,100];
            else
                ferror = uifigure('Position',[100 150 450 250]);
                uialert(ferror,'The functionality threshold for the 2nd rescheduling should be greater than the functionality threshold for the 1st rescheduling', 'Invalid functionality threshold for rescheduling');
            end                        
        end

        % Value changed function: rdSlider
        function rdSliderValueChanged(app, event)
            Qthreshold2 = app.ndSlider.Value;
            Qthreshold3 = app.rdSlider.Value;
            if gt(Qthreshold3, Qthreshold2)
                app.thSlider.Limits = [1+Qthreshold3,100];
                app.thSlider_2.Limits = [1+Qthreshold3,100];
            else
                ferror = uifigure('Position',[100 150 450 250]);
                uialert(ferror,'The functionality threshold for the 3rd rescheduling should be greater than the functionality threshold for the 2nd rescheduling', 'Invalid functionality threshold for rescheduling');
            end                        
        end

        % Value changed function: thSlider
        function thSliderValueChanged(app, event)
            Qthreshold3 = app.rdSlider.Value;
            Qthreshold4 = app.thSlider.Value;
            if gt(Qthreshold4, Qthreshold3)
                app.thSlider_2.Limits = [1+Qthreshold4,100];
            else
                ferror = uifigure('Position',[100 150 450 250]);
                uialert(ferror,'The functionality threshold for the 4th rescheduling should be greater than the functionality threshold for the 3rd rescheduling', 'Invalid functionality threshold for rescheduling');
            end
        end

        % Value changed function: 
        % UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch
        function UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitchValueChanged(app, event)
            value = app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch.Value;
            if strcmp(value,'YES')
                app.ProbabilityofthePresenceofaMobileGeneratorLabel.Enable = 'on';
                app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Enable = 'on';
                app.ProbabilityofthePresenceofaMobileGeneratorLabel.Visible = 'on';
                app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Visible = 'on';
                app.ProbabilityofthePresenceofaMobileGeneratorLabel.FontColor = 'b';
                app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.FontColor = 'b';
                app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch.FontColor = 'b';
            else
                app.ProbabilityofthePresenceofaMobileGeneratorLabel.Enable = 'off';
                app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Enable = 'off';
                app.ProbabilityofthePresenceofaMobileGeneratorLabel.Visible = 'off';
                app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Visible = 'off';                
                app.ProbabilityofthePresenceofaMobileGeneratorLabel.FontColor = 'k';
                app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.FontColor = 'k';
                app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch.FontColor = 'k';
            end
        end

        % Value changed function: 
        % RelaxprecendenceconstraintbetweensometasksSwitch
        function RelaxprecendenceconstraintbetweensometasksSwitchValueChanged(app, event)
            value = app.RelaxprecendenceconstraintbetweensometasksSwitch.Value;
            if strcmp(value,'YES')
                app.RelaxprecendenceconstraintbetweensometasksSwitch.FontColor = 'b';
            else
                app.RelaxprecendenceconstraintbetweensometasksSwitch.FontColor = 'k';
            end
        end

        % Value changed function: 
        % CoordinaterestorationplanningacrosssystemsSwitch
        function CoordinaterestorationplanningacrosssystemsSwitchValueChanged(app, event)
            value = app.CoordinaterestorationplanningacrosssystemsSwitch.Value;
            if strcmp(value, 'YES')
                app.CoordinaterestorationplanningacrosssystemsSwitch.FontColor = 'b';
            else
                app.CoordinaterestorationplanningacrosssystemsSwitch.FontColor = 'k';
            end
        end

        % Value changed function: HazardTypeDropDown_2
        function HazardTypeDropDown_2ValueChanged(app, event)
            type = app.HazardTypeDropDown_2.Value;
            if strcmp(type, 'Earthquake')
                app.HazardMapDropDown.Items = {'Hazard_ShakeMap'};
            elseif strcmp(type, 'Hurricane')
                app.HazardMapDropDown.Items = {'Hazard_WindMap'};
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            app.StopButton.Enable = 'off';     
        end

        % Image clicked function: ImagePRAISys
        function ImagePRAISysClicked(app, event)
            %Anyone of the below commands can be used.
            web('http://www.praisys.org/'); % This will open the mentioned URL in new tab in Internet Explorer.
            %web('http://www.praisys.org/'); % This will open the URL in the help browser of MATLAB.
        end

        % Image clicked function: ImageNSF
        function ImageNSFClicked(app, event)
            web('https://www.nsf.gov/'); % This will open the mentioned URL in new tab in Internet Explorer.
        end

        % Image clicked function: ImageLU
        function ImageLUClicked(app, event)
            web('https://www1.lehigh.edu/'); % This will open the mentioned URL in new tab in Internet Explorer.
        end

        % Image clicked function: ImageFAU
        function ImageFAUClicked(app, event)
            web('https://www.fau.edu/'); % This will open the mentioned URL in new tab in Internet Explorer.
        end

        % Image clicked function: ImageGSU
        function ImageGSUClicked(app, event)
            web('https://www.gsu.edu/'); % This will open the mentioned URL in new tab in Internet Explorer.
        end

        % Button pushed function: LearnmoreonthePRAISyswebsiteButton
        function LearnmoreonthePRAISyswebsiteButtonPushed(app, event)
            web('http://www.praisys.org/'); % This will open the mentioned URL in new tab in Internet Explorer.
        end

        % Button pushed function: ReadPRAISysTechnicalManualButton
        function ReadPRAISysTechnicalManualButtonPushed(app, event)
            % This web address needs to be updated as a PDF file on the
            % PRAISys website once the manual is finalized. 
            web('https://docs.google.com/document/d/1TydmFTrLH4rKRAmc6Vw445VeJVQpe81S0CkrjqziJUw/edit?usp=sharing');
        end

        % Button pushed function: CMMI1541177Button
        function CMMI1541177ButtonPushed(app, event)
            web('https://www.nsf.gov/awardsearch/showAward?AWD_ID=1541177'); % This will open the mentioned URL in new tab in Internet Explorer.            
        end

        % Button pushed function: CMMI1541089Button
        function CMMI1541089ButtonPushed(app, event)
            web('https://www.nsf.gov/awardsearch/showAward?AWD_ID=1541089'); % This will open the mentioned URL in new tab in Internet Explorer.            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PRAISysUserInterfaceLabel = uilabel(app.UIFigure);
            app.PRAISysUserInterfaceLabel.Tooltip = {'For using PRAISys with complete features, please contact us at praisy@lehigh.edu'};
            app.PRAISysUserInterfaceLabel.Text = 'PRAISys User Interface';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.SystemsofInterestPanel = uipanel(app.GeneralTab);
            app.SystemsofInterestPanel.Tooltip = {'Select infrastructure systems of interest. Selecting a system will activate the corresponding system tab. '};
            app.SystemsofInterestPanel.Title = 'System(s) of Interest';
            app.PowerCheckBox = uicheckbox(app.SystemsofInterestPanel);
            app.PowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerCheckBoxValueChanged, true);
            app.PowerCheckBox.Text = 'Power';
            app.PowerCheckBox.Value = true;
            app.CommunicationCheckBox = uicheckbox(app.SystemsofInterestPanel);
            app.CommunicationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CommunicationCheckBoxValueChanged, true);
            app.CommunicationCheckBox.Text = 'Communication';
            app.CommunicationCheckBox.Value = true;
            app.TransportationCheckBox = uicheckbox(app.SystemsofInterestPanel);
            app.TransportationCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransportationCheckBoxValueChanged, true);
            app.TransportationCheckBox.Text = 'Transportation';
            app.TransportationCheckBox.Value = true;
            app.SubComponentAnalysisSwitchLabel = uilabel(app.SystemsofInterestPanel);
            app.SubComponentAnalysisSwitchLabel.Text = 'Sub-Component Analysis';
            app.SubComponentAnalysisSwitch = uiswitch(app.SystemsofInterestPanel, 'slider');
            app.SubComponentAnalysisSwitch.Items = {'OFF', 'ON'};
            app.SubComponentAnalysisSwitch.Tooltip = {'Turn on the Sub-Component Analysis will perform damage assessment at component-level. For instance'; ' in the Transportation system'; ' a Bridge is a Component'; ' and a bridge column is a sub-component of the bridge component object.'; ''};
            app.SubComponentAnalysisSwitch.Value = 'OFF';
            app.HazardScenarioPanel = uipanel(app.GeneralTab);
            app.HazardScenarioPanel.Tooltip = {'Choose a hazard scenario for the testbed by setting the hazard type and choosing a hazard map of intensity measure(s).'};
            app.HazardScenarioPanel.Title = 'Hazard Scenario';
            app.HazardMapDropDownLabel = uilabel(app.HazardScenarioPanel);
            app.HazardMapDropDownLabel.Text = 'Hazard Map';
            app.HazardMapDropDown = uidropdown(app.HazardScenarioPanel);
            app.HazardMapDropDown.Items = {'Hazard_ShakeMap', 'Hazard_WindMap'};
            app.HazardMapDropDown.Tooltip = {'Input Data Location Type'};
            app.HazardMapDropDown.Value = 'Hazard_ShakeMap';
            app.HazardTypeDropDown_2Label = uilabel(app.HazardScenarioPanel);
            app.HazardTypeDropDown_2Label.Text = 'Hazard Type';
            app.HazardTypeDropDown_2 = uidropdown(app.HazardScenarioPanel);
            app.HazardTypeDropDown_2.Items = {'Earthquake', 'Hurricane'};
            app.HazardTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @HazardTypeDropDown_2ValueChanged, true);
            app.HazardTypeDropDown_2.Value = 'Earthquake';
            app.NeighborhoodDataPanel = uipanel(app.GeneralTab);
            app.NeighborhoodDataPanel.Tooltip = {'Choose the input file that represent the local communities as neighborhoods.'};
            app.NeighborhoodDataPanel.Title = 'Neighborhood Data:';
            app.InputDataDropDownLabel = uilabel(app.NeighborhoodDataPanel);
            app.InputDataDropDownLabel.Text = 'Input Data';
            app.InputDataDropDown = uidropdown(app.NeighborhoodDataPanel);
            app.InputDataDropDown.Items = {'Neighborhood', ''};
            app.InputDataDropDown.Tooltip = {'File Containing Neighborhood Community Data'};
            app.InputDataDropDown.Value = 'Neighborhood';
            app.RandomSamplingPanel = uipanel(app.GeneralTab);
            app.RandomSamplingPanel.Tooltip = {'Random sampling: using a random seed or not using a random seed to generate random samples.'};
            app.RandomSamplingPanel.Title = 'Random Sampling';
            app.SamplingTypeSwitchLabel = uilabel(app.RandomSamplingPanel);
            app.SamplingTypeSwitchLabel.Text = 'Sampling Type';
            app.SamplingTypeSwitch = uiswitch(app.RandomSamplingPanel, 'slider');
            app.SamplingTypeSwitch.Items = {'Using Random Seed', 'Not Using Random Seed'};
            app.SamplingTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @SamplingTypeSwitchValueChanged, true);
            app.SamplingTypeSwitch.Tooltip = {'Pseudo-Random utilizes a random seed to generate pseudo random numbers.'};
            app.SamplingTypeSwitch.Value = 'Using Random Seed';
            app.NoofTaskDurationSampleperDamageSampleSpinnerLabel = uilabel(app.RandomSamplingPanel);
            app.NoofTaskDurationSampleperDamageSampleSpinnerLabel.Text = 'No. of Task Duration Sample per Damage Sample';
            app.NoofTaskDurationSampleperDamageSampleSpinner = uispinner(app.RandomSamplingPanel);
            app.NoofTaskDurationSampleperDamageSampleSpinner.Limits = [0 Inf];
            app.NoofTaskDurationSampleperDamageSampleSpinner.Value = 4;
            app.NoofDamageScenarioSamplesSpinnerLabel = uilabel(app.RandomSamplingPanel);
            app.NoofDamageScenarioSamplesSpinnerLabel.Text = 'No. of Damage Scenario Samples';
            app.NoofDamageScenarioSamplesSpinner = uispinner(app.RandomSamplingPanel);
            app.NoofDamageScenarioSamplesSpinner.Limits = [0 Inf];
            app.NoofDamageScenarioSamplesSpinner.Value = 2;
            app.RandomSeedSpinnerLabel = uilabel(app.RandomSamplingPanel);
            app.RandomSeedSpinnerLabel.Text = 'Random Seed';
            app.RandomSeedSpinner = uispinner(app.RandomSamplingPanel);
            app.RandomSeedSpinner.Limits = [0 Inf];
            app.RandomSeedSpinner.Value = 123;
            app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel = uipanel(app.GeneralTab);
            app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel.Tooltip = {'Set up either uniform time unit (day) or non-uniform time units at different phases in the recovery simulation.'};
            app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel.Title = 'Time Unit Setting for Recovery Planning at Different Phases';
            app.Switch = uiswitch(app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel, 'slider');
            app.Switch.Items = {'OFF', 'ON'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'ON';
            app.Switch_2 = uiswitch(app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel, 'slider');
            app.Switch_2.Items = {'Uniform ', 'Nonuniform'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Tooltip = {'The uniform option uses the time unit of day and the nonuniform option uses nonuniform time units at different phases.'};
            app.Switch_2.Value = 'Uniform ';
            app.UsingtimeunitofhourintheimmediaterecoveryphaseuntilthedayLabel = uilabel(app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel);
            app.UsingtimeunitofhourintheimmediaterecoveryphaseuntilthedayLabel.Enable = 'off';
            app.UsingtimeunitofhourintheimmediaterecoveryphaseuntilthedayLabel.Text = 'Using time unit of hour in the immediate recovery phase until the                    day';
            app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner = uispinner(app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel);
            app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner.Limits = [0 Inf];
            app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner.Enable = 'off';
            app.ImmediateRecoveryPhaseuntilDaytimeunitHourSpinner.Value = 3;
            app.AfterwardsusingthetimeunitofdayintheintermediumLabel = uilabel(app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel);
            app.AfterwardsusingthetimeunitofdayintheintermediumLabel.Enable = 'off';
            app.AfterwardsusingthetimeunitofdayintheintermediumLabel.Text = 'Afterwards, using time unit of day in the intermedium recovery phase until the                    day ';
            app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner = uispinner(app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel);
            app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner.Limits = [0 Inf];
            app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner.Enable = 'off';
            app.timeunitDayIntermediumRecoveryPhaseuntilDaytimeunitDaySpinner.Value = 28;
            app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinnerLabel = uilabel(app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel);
            app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinnerLabel.Enable = 'off';
            app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinnerLabel.Text = 'Afterwards, using time unit of week in the long-term recovery phase until the                    day ';
            app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner = uispinner(app.TimeUnitSettingforRecoveryPlanningatDifferentPhasesPanel);
            app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner.Limits = [0 Inf];
            app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner.Enable = 'off';
            app.LongtermRecoveryPhaseuntilDaytimeunitWeekSpinner.Value = 168;
            app.DisasterManagementOptionsPanel = uipanel(app.GeneralTab);
            app.DisasterManagementOptionsPanel.Tooltip = {'Represent decision strategy(-ies) of disaster mitigation and restoration by choosing to use different setting ups in the following three options or not.'};
            app.DisasterManagementOptionsPanel.Title = 'Disaster Management Options';
            app.UseextraresourcestorelieffunctionalitydependencySwitchLabel = uilabel(app.DisasterManagementOptionsPanel);
            app.UseextraresourcestorelieffunctionalitydependencySwitchLabel.Tooltip = {'Mobile generators can provide temporary electricity for devices (such as telecom towers and traffic lights) that traditionally uses the electricity from external power grid.'};
            app.UseextraresourcestorelieffunctionalitydependencySwitchLabel.Text = 'Use mobile generators to relax functionality dependency on the electric power grid';
            app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch = uiswitch(app.DisasterManagementOptionsPanel, 'slider');
            app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch.Items = {'NO', 'YES'};
            app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch.ValueChangedFcn = createCallbackFcn(app, @UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitchValueChanged, true);
            app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch.Tooltip = {'Mobile generators can provide temporary electricity for devices (such as telecom towers and traffic lights) that traditionally uses the electricity from external power grid.'};
            app.UsemobilegeneratorstorelaxfunctionalitydependencyonpowerSwitch.Value = 'NO';
            app.RelaxprecendenceconstraintbetweensometasksSwitchLabel = uilabel(app.DisasterManagementOptionsPanel);
            app.RelaxprecendenceconstraintbetweensometasksSwitchLabel.Tooltip = {'Laying a temporary bridge by a damaged bridge can enable repair crews can easily cross the river to perform restoration activities. '};
            app.RelaxprecendenceconstraintbetweensometasksSwitchLabel.Text = 'Use alternative construction method to relax precendence constraints between some tasks';
            app.RelaxprecendenceconstraintbetweensometasksSwitch = uiswitch(app.DisasterManagementOptionsPanel, 'slider');
            app.RelaxprecendenceconstraintbetweensometasksSwitch.Items = {'NO', 'YES'};
            app.RelaxprecendenceconstraintbetweensometasksSwitch.ValueChangedFcn = createCallbackFcn(app, @RelaxprecendenceconstraintbetweensometasksSwitchValueChanged, true);
            app.RelaxprecendenceconstraintbetweensometasksSwitch.Tooltip = {'Laying a temporary bridge by a damaged bridge can enable repair crews can easily cross the river to perform restoration activities. '};
            app.RelaxprecendenceconstraintbetweensometasksSwitch.Value = 'NO';
            app.CoordinaterestorationplanningacrosssystemsSwitch = uiswitch(app.DisasterManagementOptionsPanel, 'slider');
            app.CoordinaterestorationplanningacrosssystemsSwitch.Items = {'NO', 'YES'};
            app.CoordinaterestorationplanningacrosssystemsSwitch.ValueChangedFcn = createCallbackFcn(app, @CoordinaterestorationplanningacrosssystemsSwitchValueChanged, true);
            app.CoordinaterestorationplanningacrosssystemsSwitch.Tooltip = {'NO:Do not coordinate restoration plants across systems.'; 'YES:Completely coordinate restoration plans for all systems together'};
            app.CoordinaterestorationplanningacrosssystemsSwitch.Value = 'NO';
            app.CoordinaterestorationplanningacrosssystemsSwitchLabel = uilabel(app.DisasterManagementOptionsPanel);
            app.CoordinaterestorationplanningacrosssystemsSwitchLabel.Text = 'Develop restoration plans for different systems with the complete coordination';
            app.ImportExporttheInputDataPanel = uipanel(app.GeneralTab);
            app.ImportExporttheInputDataPanel.Tooltip = {'Import/Export the input file (Input.txt).'};
            app.ImportExporttheInputDataPanel.Title = 'Import / Export the Input Data';
            app.ImportSettingsButton = uibutton(app.ImportExporttheInputDataPanel, 'push');
            app.ImportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSettingsButtonPushed, true);
            app.ImportSettingsButton.Enable = 'off';
            app.ImportSettingsButton.Tooltip = {'Currently Unavailable'};
            app.ImportSettingsButton.Text = 'Import Settings';
            app.NoteTheinputfileisatextfileLabel = uilabel(app.ImportExporttheInputDataPanel);
            app.NoteTheinputfileisatextfileLabel.Text = 'Note: The input file is a text file.';
            app.ExportSettingsButton = uibutton(app.ImportExporttheInputDataPanel, 'push');
            app.ExportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton.Text = 'Export Settings';
            app.ImportedLabel = uilabel(app.ImportExporttheInputDataPanel);
            app.ImportedLabel.Visible = 'off';
            app.ImportedLabel.Text = 'Imported!';
            app.ExportedLabel = uilabel(app.ImportExporttheInputDataPanel);
            app.ExportedLabel.Visible = 'off';
            app.ExportedLabel.Text = 'Exported!';
            app.HistorySavingPanel = uipanel(app.GeneralTab);
            app.HistorySavingPanel.Tooltip = {'Save the history of all computational results or not'};
            app.HistorySavingPanel.Title = 'History Saving';
            app.Switch_3 = uiswitch(app.HistorySavingPanel, 'slider');
            app.Switch_3.Items = {'NO', 'YES'};
            app.Switch_3.Value = 'NO';
            app.MiscellaneousPanel = uipanel(app.GeneralTab);
            app.MiscellaneousPanel.Tooltip = {'Set up the profile number for parallel computing. Leave at 0 if unknown'};
            app.MiscellaneousPanel.Title = 'Miscellaneous';
            app.ParallelComputingSpinnerLabel = uilabel(app.MiscellaneousPanel);
            app.ParallelComputingSpinnerLabel.Text = 'Parallel Computing ';
            app.ParallelComputingSpinner = uispinner(app.MiscellaneousPanel);
            app.ParallelComputingSpinner.Limits = [0 Inf];
            app.ProbabilityofthePresenceofaMobileGeneratorLabel = uilabel(app.GeneralTab);
            app.ProbabilityofthePresenceofaMobileGeneratorLabel.Enable = 'off';
            app.ProbabilityofthePresenceofaMobileGeneratorLabel.Text = '- Probability of the Presence of a Mobile Generator (%)';
            app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2 = uispinner(app.GeneralTab);
            app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Limits = [0 100];
            app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Enable = 'off';
            app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Tooltip = {'Mobile generators can provide temporary electricity to electric devices to relax the functionality dependency of electric devices on the electric power service.'};
            app.ProbabilityofthePresenceofaMobileGeneratorSpinner_2.Value = 50;
            app.SchedulingTab = uitab(app.TabGroup);
            app.SchedulingTab.Title = 'Scheduling';
            app.DeveloptheRestorationPlanPanel = uipanel(app.SchedulingTab);
            app.DeveloptheRestorationPlanPanel.Tooltip = {'Set up input for developing a restoration plan.'; ''};
            app.DeveloptheRestorationPlanPanel.Title = 'Develop the Restoration Plan';
            app.OptimizationChoiceDropDownLabel = uilabel(app.DeveloptheRestorationPlanPanel);
            app.OptimizationChoiceDropDownLabel.Text = {'When selecting the Optimization Scheme to develop the restoration plan, please selec an optimization model'; ''};
            app.OptimizationChoiceDropDown = uidropdown(app.DeveloptheRestorationPlanPanel);
            app.OptimizationChoiceDropDown.Items = {'OptimizationRCPCP_FT', 'OptimizationRCPCP_ST', 'OptimizationFlexible', 'SubOptimizationRCPCP_FT', 'SubOptimizationRCPCP_ST'};
            app.OptimizationChoiceDropDown.Tooltip = {'Choose the optimization formulation after selecting the optimization scheme.'};
            app.OptimizationChoiceDropDown.Value = 'OptimizationFlexible';
            app.RestorationPlanningSchemeSwitchLabel = uilabel(app.DeveloptheRestorationPlanPanel);
            app.RestorationPlanningSchemeSwitchLabel.Text = 'Restoration Planning Scheme';
            app.RestorationPlanningSchemeSwitch = uiswitch(app.DeveloptheRestorationPlanPanel, 'slider');
            app.RestorationPlanningSchemeSwitch.Items = {'Priority', 'Optimization'};
            app.RestorationPlanningSchemeSwitch.ValueChangedFcn = createCallbackFcn(app, @RestorationPlanningSchemeSwitchValueChanged, true);
            app.RestorationPlanningSchemeSwitch.Tooltip = {'Select the scheme (proirity vs optimization) for developing the restoration plan under a given damage scenario. 1. You can set up different priority policy in the System Tab when choosing the Priority Scheme. 2. You can select the optimization model in the underneath options when choosing the Optimization Scheme.'};
            app.RestorationPlanningSchemeSwitch.Value = 'Priority';
            app.ReSchedulingOptionSwitchLabel = uilabel(app.DeveloptheRestorationPlanPanel);
            app.ReSchedulingOptionSwitchLabel.Text = 'Re-Scheduling Option';
            app.ReSchedulingOptionSwitch = uiswitch(app.DeveloptheRestorationPlanPanel, 'slider');
            app.ReSchedulingOptionSwitch.Items = {'OFF', 'ON'};
            app.ReSchedulingOptionSwitch.ValueChangedFcn = createCallbackFcn(app, @ReSchedulingOptionSwitchValueChanged, true);
            app.ReSchedulingOptionSwitch.Tooltip = {'Would you like to update the restoration plan during the restoration?'};
            app.ReSchedulingOptionSwitch.Value = 'OFF';
            app.TimeHorizonDaySpinnerLabel = uilabel(app.DeveloptheRestorationPlanPanel);
            app.TimeHorizonDaySpinnerLabel.Text = 'Time Horizon (Day) ';
            app.TimeHorizonDaySpinner = uispinner(app.DeveloptheRestorationPlanPanel);
            app.TimeHorizonDaySpinner.Limits = [1 Inf];
            app.TimeHorizonDaySpinner.ValueChangedFcn = createCallbackFcn(app, @TimeHorizonDaySpinnerValueChanged, true);
            app.TimeHorizonDaySpinner.Tooltip = {'How many days after the initial hazard event would you like to simulate? '};
            app.TimeHorizonDaySpinner.Value = 196;
            app.ReSchedulingOptionsPanel = uipanel(app.SchedulingTab);
            app.ReSchedulingOptionsPanel.Tooltip = {'If you would like to reschedule the restoration plan'; ' please choose the number of reschedule to develop an updated plan when the sytem functionality recovers to a threshold and/or when the system recoverys to a time threshold.'};
            app.ReSchedulingOptionsPanel.Title = 'Re-Scheduling Options';
            app.RescheduleataCertainTimeDayPanel = uipanel(app.ReSchedulingOptionsPanel);
            app.RescheduleataCertainTimeDayPanel.Tooltip = {'If you would like to reschedule the restoration plan'; ' please choose the number of reschedule to develop an updated plan when the system recoverys to a time threshold.'};
            app.RescheduleataCertainTimeDayPanel.Title = 'Reschedule at a Certain Time (Day)';
            app.NoofReschedulesSpinnerLabel = uilabel(app.RescheduleataCertainTimeDayPanel);
            app.NoofReschedulesSpinnerLabel.Text = 'No. of Reschedules';
            app.NoofReschedulesSpinner = uispinner(app.RescheduleataCertainTimeDayPanel);
            app.NoofReschedulesSpinner.Limits = [0 5];
            app.NoofReschedulesSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofReschedulesSpinnerValueChanged, true);
            app.NoofReschedulesSpinner.Value = 1;
            app.streschedulingtimedaySpinnerLabel = uilabel(app.RescheduleataCertainTimeDayPanel);
            app.streschedulingtimedaySpinnerLabel.Text = '1st rescheduling time (day)';
            app.streschedulingtimedaySpinner = uispinner(app.RescheduleataCertainTimeDayPanel);
            app.streschedulingtimedaySpinner.Limits = [0 Inf];
            app.streschedulingtimedaySpinner.ValueChangedFcn = createCallbackFcn(app, @streschedulingtimedaySpinnerValueChanged, true);
            app.streschedulingtimedaySpinner.Value = 30;
            app.ndreschedulingtimedaySpinnerLabel = uilabel(app.RescheduleataCertainTimeDayPanel);
            app.ndreschedulingtimedaySpinnerLabel.Text = '2nd rescheduling time (day)';
            app.ndreschedulingtimedaySpinner = uispinner(app.RescheduleataCertainTimeDayPanel);
            app.ndreschedulingtimedaySpinner.Limits = [0 Inf];
            app.ndreschedulingtimedaySpinner.ValueChangedFcn = createCallbackFcn(app, @ndreschedulingtimedaySpinnerValueChanged, true);
            app.rdreschedulingtimedaySpinnerLabel = uilabel(app.RescheduleataCertainTimeDayPanel);
            app.rdreschedulingtimedaySpinnerLabel.Text = '3rd rescheduling time (day)';
            app.rdreschedulingtimedaySpinner = uispinner(app.RescheduleataCertainTimeDayPanel);
            app.rdreschedulingtimedaySpinner.Limits = [0 Inf];
            app.rdreschedulingtimedaySpinner.ValueChangedFcn = createCallbackFcn(app, @rdreschedulingtimedaySpinnerValueChanged, true);
            app.threschedulingtimedaySpinnerLabel = uilabel(app.RescheduleataCertainTimeDayPanel);
            app.threschedulingtimedaySpinnerLabel.Text = '4th rescheduling time (day)';
            app.threschedulingtimedaySpinner = uispinner(app.RescheduleataCertainTimeDayPanel);
            app.threschedulingtimedaySpinner.Limits = [0 Inf];
            app.threschedulingtimedaySpinner.ValueChangedFcn = createCallbackFcn(app, @threschedulingtimedaySpinnerValueChanged, true);
            app.threschedulingtimedaySpinner_2Label = uilabel(app.RescheduleataCertainTimeDayPanel);
            app.threschedulingtimedaySpinner_2Label.Text = '5th rescheduling time (day)';
            app.threschedulingtimedaySpinner_2 = uispinner(app.RescheduleataCertainTimeDayPanel);
            app.threschedulingtimedaySpinner_2.Limits = [0 Inf];
            app.NotePositveintegerlessthanTimeHorizonLabel = uilabel(app.RescheduleataCertainTimeDayPanel);
            app.NotePositveintegerlessthanTimeHorizonLabel.Text = 'The following reschduling time should be positve integers.';
            app.RescheduleataFunctionalityThesholdPanel = uipanel(app.ReSchedulingOptionsPanel);
            app.RescheduleataFunctionalityThesholdPanel.Tooltip = {'If you would like to reschedule the restoration plan'; ' please choose the number of reschedule to develop an updated plan when the sytem functionality recovers to a threshold.'};
            app.RescheduleataFunctionalityThesholdPanel.Title = 'Reschedule at a Functionality Theshold (%)';
            app.NoofReschedulesSpinner_2Label = uilabel(app.RescheduleataFunctionalityThesholdPanel);
            app.NoofReschedulesSpinner_2Label.Text = 'No. of Reschedules';
            app.NoofReschedulesSpinner_2 = uispinner(app.RescheduleataFunctionalityThesholdPanel);
            app.NoofReschedulesSpinner_2.Limits = [0 5];
            app.NoofReschedulesSpinner_2.ValueChangedFcn = createCallbackFcn(app, @NoofReschedulesSpinner_2ValueChanged, true);
            app.NoofReschedulesSpinner_2.Value = 1;
            app.stSliderLabel = uilabel(app.RescheduleataFunctionalityThesholdPanel);
            app.stSliderLabel.Text = '1st ';
            app.stSlider = uislider(app.RescheduleataFunctionalityThesholdPanel);
            app.stSlider.MajorTicks = [0 20 40 60 80 100];
            app.stSlider.ValueChangedFcn = createCallbackFcn(app, @stSliderValueChanged, true);
            app.stSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.stSlider.Value = 60;
            app.ndSliderLabel = uilabel(app.RescheduleataFunctionalityThesholdPanel);
            app.ndSliderLabel.Text = '2nd';
            app.ndSlider = uislider(app.RescheduleataFunctionalityThesholdPanel);
            app.ndSlider.MajorTicks = [0 20 40 60 80 100];
            app.ndSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.ndSlider.ValueChangedFcn = createCallbackFcn(app, @ndSliderValueChanged, true);
            app.ndSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.rdSliderLabel = uilabel(app.RescheduleataFunctionalityThesholdPanel);
            app.rdSliderLabel.Text = '3rd';
            app.rdSlider = uislider(app.RescheduleataFunctionalityThesholdPanel);
            app.rdSlider.MajorTicks = [0 20 40 60 80 100];
            app.rdSlider.ValueChangedFcn = createCallbackFcn(app, @rdSliderValueChanged, true);
            app.rdSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.thSliderLabel = uilabel(app.RescheduleataFunctionalityThesholdPanel);
            app.thSliderLabel.Text = '4th';
            app.thSlider = uislider(app.RescheduleataFunctionalityThesholdPanel);
            app.thSlider.MajorTicks = [0 20 40 60 80 100];
            app.thSlider.ValueChangedFcn = createCallbackFcn(app, @thSliderValueChanged, true);
            app.thSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.thSlider_2Label = uilabel(app.RescheduleataFunctionalityThesholdPanel);
            app.thSlider_2Label.Text = '5th';
            app.thSlider_2 = uislider(app.RescheduleataFunctionalityThesholdPanel);
            app.thSlider_2.MajorTicks = [0 20 40 60 80 100];
            app.thSlider_2.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.PowerTab = uitab(app.TabGroup);
            app.PowerTab.Title = 'Power';
            app.PriorityPolicyCriterionButtonGroup = uibuttongroup(app.PowerTab);
            app.PriorityPolicyCriterionButtonGroup.Tooltip = {'If the Priority Scheme is chosen on the General Tab please choose what the priority criteria you would like to use.'};
            app.PriorityPolicyCriterionButtonGroup.Title = 'Priority Policy Criterion';
            app.VoltageButton = uiradiobutton(app.PriorityPolicyCriterionButtonGroup);
            app.VoltageButton.Tooltip = {'Develop the restoration plan by ranking tasks for repairing damaged components with a higher voltage first.'};
            app.VoltageButton.Text = 'Voltage';
            app.VoltageButton.Value = true;
            app.PopulationservedbycomponentButton = uiradiobutton(app.PriorityPolicyCriterionButtonGroup);
            app.PopulationservedbycomponentButton.Tooltip = {'Develop the restoration plan by ranking tasks for repairing damaged components that serve the most population first.'};
            app.PopulationservedbycomponentButton.Text = 'Population served by component';
            app.RandomlyButton = uiradiobutton(app.PriorityPolicyCriterionButtonGroup);
            app.RandomlyButton.Tooltip = {'Develop the restoration plan by randomly repairing damaged component.'};
            app.RandomlyButton.Text = 'Randomly';
            app.NoofhouseholdsservedbycomponentButton = uiradiobutton(app.PriorityPolicyCriterionButtonGroup);
            app.NoofhouseholdsservedbycomponentButton.Tooltip = {'Develop the restoration plan by ranking tasks for repairing damaged components that serve the greatest number of households first.'};
            app.NoofhouseholdsservedbycomponentButton.Text = 'No. of households served by component';
            app.InputFileforComponentsPanel_3 = uipanel(app.PowerTab);
            app.InputFileforComponentsPanel_3.Tooltip = {'Choose which Components to be considered in analysis for the Power system.'; ''};
            app.InputFileforComponentsPanel_3.Title = 'Input File for Components';
            app.PowerPlantCheckBox = uicheckbox(app.InputFileforComponentsPanel_3);
            app.PowerPlantCheckBox.Text = 'Power Plant';
            app.PowerPlantCheckBox.Value = true;
            app.SubstationCheckBox = uicheckbox(app.InputFileforComponentsPanel_3);
            app.SubstationCheckBox.Text = 'Substation';
            app.SubstationCheckBox.Value = true;
            app.PowerlineCheckBox = uicheckbox(app.InputFileforComponentsPanel_3);
            app.PowerlineCheckBox.Text = 'Powerline';
            app.PowerlineCheckBox.Value = true;
            app.TransmissionTowerCheckBox = uicheckbox(app.InputFileforComponentsPanel_3);
            app.TransmissionTowerCheckBox.Text = 'Transmission Tower';
            app.FunctionalityMetricSelectionPanel = uipanel(app.PowerTab);
            app.FunctionalityMetricSelectionPanel.Tooltip = {'Choose metric(s) of the system functionality'};
            app.FunctionalityMetricSelectionPanel.Title = 'Functionality Metric Selection';
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3Label = uilabel(app.FunctionalityMetricSelectionPanel);
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3Label.Text = 'Graph Theory Properties for System Functionality';
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3 = uiswitch(app.FunctionalityMetricSelectionPanel, 'slider');
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3.Items = {'OFF', 'ON'};
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3.ValueChangedFcn = createCallbackFcn(app, @GraphTheoryPropertiesforSystemFunctionalitySwitch_3ValueChanged, true);
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_3.Value = 'OFF';
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel = uipanel(app.FunctionalityMetricSelectionPanel);
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel.Title = 'Functionality Metrics related to Graph Theory';
            app.AveragedegreeCheckBox_5 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel);
            app.AveragedegreeCheckBox_5.Text = 'Average degree';
            app.CharacteristicpathlengthCheckBox_5 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel);
            app.CharacteristicpathlengthCheckBox_5.Text = 'Characteristic path length';
            app.NetworkefficiencyCheckBox_5 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel);
            app.NetworkefficiencyCheckBox_5.Text = 'Network efficiency';
            app.ClusteringcoefficientclosedneighborhoodCheckBox_5 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel);
            app.ClusteringcoefficientclosedneighborhoodCheckBox_5.Text = 'Clustering coefficient (closed neighborhood)';
            app.LocalefficiencyclosedneighborhoodCheckBox_5 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel);
            app.LocalefficiencyclosedneighborhoodCheckBox_5.Text = 'Local efficiency (closed neighborhood)';
            app.ClusteringcoefficientopenneighborhoodCheckBox_5 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel);
            app.ClusteringcoefficientopenneighborhoodCheckBox_5.Enable = 'off';
            app.ClusteringcoefficientopenneighborhoodCheckBox_5.Text = 'Clustering coefficient (open neighborhood)';
            app.LocalefficiencyopenneighborhoodCheckBox_5 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel);
            app.LocalefficiencyopenneighborhoodCheckBox_5.Enable = 'off';
            app.LocalefficiencyopenneighborhoodCheckBox_5.Text = 'Local efficiency (open neighborhood)';
            app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel = uipanel(app.FunctionalityMetricSelectionPanel);
            app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel.Title = 'Functionality Metrics not related to Graph Theory';
            app.PercentageoffunctionalsubstationsbynumberCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel);
            app.PercentageoffunctionalsubstationsbynumberCheckBox.Text = 'Percentage of functional substations by number';
            app.PercentageoffunctionalsubstationsbynumberCheckBox.Value = true;
            app.PercentageoffunctionalsubstationbythemaximumvoltageCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel);
            app.PercentageoffunctionalsubstationbythemaximumvoltageCheckBox.Text = 'Percentage of functional substation by the maximum voltage';
            app.PercentageoffunctionalsubstationbythemaximumvoltageCheckBox.Value = true;
            app.PercentageofpopulationwithPowerServiceatNeighbohoodendsCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel);
            app.PercentageofpopulationwithPowerServiceatNeighbohoodendsCheckBox.Text = 'Percentage of population with power service at municipality-level';
            app.PercentageofpopulationwithPowerServiceatNeighbohoodendsCheckBox.Value = true;
            app.PercentageoffunctionalsubstationbythepopulationservedCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel);
            app.PercentageoffunctionalsubstationbythepopulationservedCheckBox.Text = 'Percentage of functional substation by the population served';
            app.PercentageoffunctionalsubstationbythepopulationservedCheckBox.Value = true;
            app.PercentageoffunctionalsubstationbythehouseholdCheckBox_3 = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel);
            app.PercentageoffunctionalsubstationbythehouseholdCheckBox_3.Text = 'Percentage of functional substation by the number of household served';
            app.PercentageoffunctionalsubstationbythehouseholdCheckBox_3.Value = true;
            app.ResiilienceMetricSelectionPanel = uipanel(app.PowerTab);
            app.ResiilienceMetricSelectionPanel.Tooltip = {'Choose metric(s) of resilience'};
            app.ResiilienceMetricSelectionPanel.Title = 'Resiilience Metric Selection';
            app.ResilienceIndexCheckBox = uicheckbox(app.ResiilienceMetricSelectionPanel);
            app.ResilienceIndexCheckBox.Text = 'Resilience Index';
            app.ResilienceIndexCheckBox.Value = true;
            app.ResilienceLossCheckBox = uicheckbox(app.ResiilienceMetricSelectionPanel);
            app.ResilienceLossCheckBox.Text = 'Resilience Loss';
            app.ResilienceLossCheckBox.Value = true;
            app.RapidityCheckBox = uicheckbox(app.ResiilienceMetricSelectionPanel);
            app.RapidityCheckBox.Text = 'Rapidity';
            app.RapidityCheckBox.Value = true;
            app.RestorationTimeCheckBox = uicheckbox(app.ResiilienceMetricSelectionPanel);
            app.RestorationTimeCheckBox.Text = 'Restoration Time';
            app.RestorationTimeCheckBox.Value = true;
            app.ResourceConstraintsPanel_3 = uipanel(app.PowerTab);
            app.ResourceConstraintsPanel_3.Tooltip = {'Set up how many power resources are avilable for restoration activities'};
            app.ResourceConstraintsPanel_3.Title = 'Resource Constraints';
            app.CrewSpinnerLabel = uilabel(app.ResourceConstraintsPanel_3);
            app.CrewSpinnerLabel.Text = 'Crew';
            app.CrewSpinner = uispinner(app.ResourceConstraintsPanel_3);
            app.CrewSpinner.Limits = [0 Inf];
            app.CrewSpinner.Value = 30;
            app.EquippedTruckSpinner_3Label = uilabel(app.ResourceConstraintsPanel_3);
            app.EquippedTruckSpinner_3Label.Text = 'Equipped Truck';
            app.EquippedTruckSpinner_3 = uispinner(app.ResourceConstraintsPanel_3);
            app.EquippedTruckSpinner_3.Limits = [0 Inf];
            app.EquippedTruckSpinner_3.Value = 30;
            app.rdTypeofResourceLabel = uilabel(app.ResourceConstraintsPanel_3);
            app.rdTypeofResourceLabel.Enable = 'off';
            app.rdTypeofResourceLabel.Text = '3rd Type of Resource';
            app.rdTypeofResourceSpinner = uispinner(app.ResourceConstraintsPanel_3);
            app.rdTypeofResourceSpinner.Limits = [0 Inf];
            app.rdTypeofResourceSpinner.Enable = 'off';
            app.rdTypeofResourceSpinner.Value = 10;
            app.thTypeofResourceSpinnerLabel = uilabel(app.ResourceConstraintsPanel_3);
            app.thTypeofResourceSpinnerLabel.Enable = 'off';
            app.thTypeofResourceSpinnerLabel.Text = '4th Type of Resource';
            app.thTypeofResourceSpinner = uispinner(app.ResourceConstraintsPanel_3);
            app.thTypeofResourceSpinner.Limits = [0 Inf];
            app.thTypeofResourceSpinner.Enable = 'off';
            app.thTypeofResourceSpinner.Value = 10;
            app.CommunicationTab = uitab(app.TabGroup);
            app.CommunicationTab.Title = 'Communication';
            app.PriorityPolicyCriterionButtonGroup_2 = uibuttongroup(app.CommunicationTab);
            app.PriorityPolicyCriterionButtonGroup_2.Tooltip = {'If the Priority Scheme is chosen on the General Tab please select the priority policy criteria for developping the restoration plan.'};
            app.PriorityPolicyCriterionButtonGroup_2.Title = 'Priority Policy Criterion';
            app.NoofhouseholdsservedbycomponentButton_2 = uiradiobutton(app.PriorityPolicyCriterionButtonGroup_2);
            app.NoofhouseholdsservedbycomponentButton_2.Tooltip = {'Develop the restoration plan by ranking tasks for repairing damaged components that serve the greatest number of households first.'};
            app.NoofhouseholdsservedbycomponentButton_2.Text = 'No. of households served by component';
            app.NoofhouseholdsservedbycomponentButton_2.Value = true;
            app.RandomlyButton_2 = uiradiobutton(app.PriorityPolicyCriterionButtonGroup_2);
            app.RandomlyButton_2.Tooltip = {'Develop the restoration plan by randomly repairing damaged component.'};
            app.RandomlyButton_2.Text = 'Randomly';
            app.PopulationservedbycomponentButton_2 = uiradiobutton(app.PriorityPolicyCriterionButtonGroup_2);
            app.PopulationservedbycomponentButton_2.Tooltip = {'Develop the restoration plan by ranking tasks for repairing damaged components that serve the most population first.'};
            app.PopulationservedbycomponentButton_2.Text = 'Population served by component';
            app.InputFileforComponentsPanel_2 = uipanel(app.CommunicationTab);
            app.InputFileforComponentsPanel_2.Tooltip = {'Choose which Communication Components to be considered in analysis'};
            app.InputFileforComponentsPanel_2.Title = 'Input File for Components';
            app.CentralOfficeCheckBox = uicheckbox(app.InputFileforComponentsPanel_2);
            app.CentralOfficeCheckBox.Text = 'Central Office';
            app.CentralOfficeCheckBox.Value = true;
            app.CommunicationTowerCheckBox = uicheckbox(app.InputFileforComponentsPanel_2);
            app.CommunicationTowerCheckBox.Text = 'Communication Tower';
            app.CommunicationTowerCheckBox.Value = true;
            app.MetricSelectionSystemFunctionalityPanel_2 = uipanel(app.CommunicationTab);
            app.MetricSelectionSystemFunctionalityPanel_2.Tooltip = {'Choose metric(s) of the system functionality'};
            app.MetricSelectionSystemFunctionalityPanel_2.Title = 'Metric Selection - System Functionality';
            app.GraphTheoryPropertiesforSystemFunctionalitySwitchLabel = uilabel(app.MetricSelectionSystemFunctionalityPanel_2);
            app.GraphTheoryPropertiesforSystemFunctionalitySwitchLabel.Text = 'Graph Theory Properties for System Functionality';
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch = uiswitch(app.MetricSelectionSystemFunctionalityPanel_2, 'slider');
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch.Items = {'OFF', 'ON'};
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch.ValueChangedFcn = createCallbackFcn(app, @GraphTheoryPropertiesforSystemFunctionalitySwitchValueChanged, true);
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch.Value = 'OFF';
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2 = uipanel(app.MetricSelectionSystemFunctionalityPanel_2);
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2.Title = 'Functionality Metrics related to Graph Theory';
            app.AveragedegreeCheckBox_4 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2);
            app.AveragedegreeCheckBox_4.Text = 'Average degree';
            app.CharacteristicpathlengthCheckBox_4 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2);
            app.CharacteristicpathlengthCheckBox_4.Text = 'Characteristic path length';
            app.NetworkefficiencyCheckBox_4 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2);
            app.NetworkefficiencyCheckBox_4.Text = 'Network efficiency';
            app.ClusteringcoefficientclosedneighborhoodCheckBox_4 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2);
            app.ClusteringcoefficientclosedneighborhoodCheckBox_4.Text = 'Clustering coefficient (closed neighborhood)';
            app.LocalefficiencyclosedneighborhoodCheckBox_4 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2);
            app.LocalefficiencyclosedneighborhoodCheckBox_4.Text = 'Local efficiency (closed neighborhood)';
            app.ClusteringcoefficientopenneighborhoodCheckBox_4 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2);
            app.ClusteringcoefficientopenneighborhoodCheckBox_4.Enable = 'off';
            app.ClusteringcoefficientopenneighborhoodCheckBox_4.Text = 'Clustering coefficient (open neighborhood)';
            app.LocalefficiencyopenneighborhoodCheckBox_4 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_2);
            app.LocalefficiencyopenneighborhoodCheckBox_4.Enable = 'off';
            app.LocalefficiencyopenneighborhoodCheckBox_4.Text = 'Local efficiency (open neighborhood)';
            app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2 = uipanel(app.MetricSelectionSystemFunctionalityPanel_2);
            app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2.Title = 'Functionality Metrics not related to Graph Theory';
            app.PercentageoffunctionalcentralofficesandcommtowersCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2);
            app.PercentageoffunctionalcentralofficesandcommtowersCheckBox.Text = 'Percentage of functional central offices and functional comm towers by number';
            app.PercentageoffunctionalcentralofficesandcommtowersCheckBox.Value = true;
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2);
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox.Text = 'Percentage of functional comm towers by number';
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox.Value = true;
            app.PercentageofpopulationwithCommServiceatNeighbohoodendsCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2);
            app.PercentageofpopulationwithCommServiceatNeighbohoodendsCheckBox.Text = 'Percentage of population with comm. service at municipality-level';
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_2 = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2);
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_2.Text = 'Percentage of functional comm towers by the population served';
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_2.Value = true;
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_3 = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_2);
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_3.Text = 'Percentage of functional comm towers by the number of household served';
            app.WeightednetworkwiththeequalweightforeveryCommTowerCheckBox_3.Value = true;
            app.ResiilienceMetricSelectionPanel_2 = uipanel(app.CommunicationTab);
            app.ResiilienceMetricSelectionPanel_2.Tooltip = {'Choose metric(s) of resilience'};
            app.ResiilienceMetricSelectionPanel_2.Title = 'Resiilience Metric Selection ';
            app.ResilienceIndexCheckBox_2 = uicheckbox(app.ResiilienceMetricSelectionPanel_2);
            app.ResilienceIndexCheckBox_2.Text = 'Resilience Index';
            app.ResilienceIndexCheckBox_2.Value = true;
            app.ResilienceLossCheckBox_2 = uicheckbox(app.ResiilienceMetricSelectionPanel_2);
            app.ResilienceLossCheckBox_2.Text = 'Resilience Loss';
            app.ResilienceLossCheckBox_2.Value = true;
            app.RapidityCheckBox_2 = uicheckbox(app.ResiilienceMetricSelectionPanel_2);
            app.RapidityCheckBox_2.Text = 'Rapidity';
            app.RapidityCheckBox_2.Value = true;
            app.RestorationTimeCheckBox_2 = uicheckbox(app.ResiilienceMetricSelectionPanel_2);
            app.RestorationTimeCheckBox_2.Text = 'Restoration Time';
            app.RestorationTimeCheckBox_2.Value = true;
            app.ResourceConstraintsPanel = uipanel(app.CommunicationTab);
            app.ResourceConstraintsPanel.Tooltip = {'Set up how many communication resources are avilable for restoration activities'};
            app.ResourceConstraintsPanel.Title = 'Resource Constraints';
            app.CrewSpinner_2Label = uilabel(app.ResourceConstraintsPanel);
            app.CrewSpinner_2Label.Text = 'Crew';
            app.CrewSpinner_2 = uispinner(app.ResourceConstraintsPanel);
            app.CrewSpinner_2.Limits = [0 Inf];
            app.CrewSpinner_2.Value = 80;
            app.EquippedTruckSpinner_2Label = uilabel(app.ResourceConstraintsPanel);
            app.EquippedTruckSpinner_2Label.Text = 'Equipped Truck';
            app.EquippedTruckSpinner_2 = uispinner(app.ResourceConstraintsPanel);
            app.EquippedTruckSpinner_2.Limits = [0 Inf];
            app.EquippedTruckSpinner_2.Value = 80;
            app.rdTypeofResourceSpinner_2Label = uilabel(app.ResourceConstraintsPanel);
            app.rdTypeofResourceSpinner_2Label.Enable = 'off';
            app.rdTypeofResourceSpinner_2Label.Text = '3rd Type of Resource';
            app.rdTypeofResourceSpinner_2 = uispinner(app.ResourceConstraintsPanel);
            app.rdTypeofResourceSpinner_2.Limits = [0 Inf];
            app.rdTypeofResourceSpinner_2.Enable = 'off';
            app.rdTypeofResourceSpinner_2.Value = 20;
            app.thTypeofResourceSpinner_2Label = uilabel(app.ResourceConstraintsPanel);
            app.thTypeofResourceSpinner_2Label.Enable = 'off';
            app.thTypeofResourceSpinner_2Label.Text = '4th Type of Resource';
            app.thTypeofResourceSpinner_2 = uispinner(app.ResourceConstraintsPanel);
            app.thTypeofResourceSpinner_2.Limits = [0 Inf];
            app.thTypeofResourceSpinner_2.Enable = 'off';
            app.thTypeofResourceSpinner_2.Value = 20;
            app.TransportationTab = uitab(app.TabGroup);
            app.TransportationTab.Tooltip = {'Set up input for the transporation system'};
            app.TransportationTab.Title = 'Transportation';
            app.PriorityPolicyCriterionButtonGroup_3 = uibuttongroup(app.TransportationTab);
            app.PriorityPolicyCriterionButtonGroup_3.Tooltip = {'If the Priority Scheme is chosen on the General Tab please select the priority policy criteria for developping the restoration plan.'};
            app.PriorityPolicyCriterionButtonGroup_3.Title = 'Priority Policy Criterion';
            app.LengthofBridgeRoadButton = uiradiobutton(app.PriorityPolicyCriterionButtonGroup_3);
            app.LengthofBridgeRoadButton.Tooltip = {'Develop the restoration plan by ranking tasks for repairing damaged component(s) that has/have the greatest length first.'};
            app.LengthofBridgeRoadButton.Text = 'Length of Bridge/Road';
            app.LengthofBridgeRoadButton.Value = true;
            app.TrafficVolumeButton = uiradiobutton(app.PriorityPolicyCriterionButtonGroup_3);
            app.TrafficVolumeButton.Tooltip = {'Develop the restoration plan by ranking tasks for repairing damaged components that serve the most traffic (AADT) first.'};
            app.TrafficVolumeButton.Text = 'Traffic Volume';
            app.RandomlyButton_3 = uiradiobutton(app.PriorityPolicyCriterionButtonGroup_3);
            app.RandomlyButton_3.Tooltip = {'Develop the restoration plan by randomly repairing damaged component.'};
            app.RandomlyButton_3.Text = 'Randomly';
            app.InputFileforComponentsPanel = uipanel(app.TransportationTab);
            app.InputFileforComponentsPanel.Tooltip = {'Choose which Transportation Components to be considered in analysis. Choose whether roads should be analyzed as directional (realistic) '};
            app.InputFileforComponentsPanel.Title = 'Input File for Components';
            app.RoadNodeCheckBox = uicheckbox(app.InputFileforComponentsPanel);
            app.RoadNodeCheckBox.Text = 'Road Node';
            app.RoadNodeCheckBox.Value = true;
            app.RoadLinkCheckBox = uicheckbox(app.InputFileforComponentsPanel);
            app.RoadLinkCheckBox.ValueChangedFcn = createCallbackFcn(app, @RoadLinkCheckBoxValueChanged, true);
            app.RoadLinkCheckBox.Text = 'Road Link';
            app.RoadLinkCheckBox.Value = true;
            app.BridgeCheckBox = uicheckbox(app.InputFileforComponentsPanel);
            app.BridgeCheckBox.Text = 'Bridge';
            app.BridgeCheckBox.Value = true;
            app.TrafficLightCheckBox = uicheckbox(app.InputFileforComponentsPanel);
            app.TrafficLightCheckBox.Text = 'Traffic Light';
            app.TrafficLightCheckBox.Value = true;
            app.RoadLinkTypeSwitchLabel = uilabel(app.InputFileforComponentsPanel);
            app.RoadLinkTypeSwitchLabel.Text = {'Road Link Type'; ''};
            app.RoadLinkTypeSwitch = uiswitch(app.InputFileforComponentsPanel, 'slider');
            app.RoadLinkTypeSwitch.Items = {'Directional', ' Not Directional'};
            app.RoadLinkTypeSwitch.Tooltip = {'Directional: Roads will be analyzed as one-directional.'; 'Non Directional: Roads will be treated as two-directional'};
            app.RoadLinkTypeSwitch.Value = 'Directional';
            app.InterdependenceTransporationDelayPanel = uipanel(app.TransportationTab);
            app.InterdependenceTransporationDelayPanel.Tooltip = {'Whether considering that a major transportation functionality disruption causes a delaying factor in restoration activities. If so provide the delaying coefficient (prolonging task duration) as well as the ‘Qtran0’ value (the transportation functionality threshold where the delaying is in effect when Qtrans<Qtrans0)'};
            app.InterdependenceTransporationDelayPanel.Title = 'Interdependence Transporation Delay';
            app.TransportDelayEffectSwitchLabel = uilabel(app.InterdependenceTransporationDelayPanel);
            app.TransportDelayEffectSwitchLabel.Text = 'Transport Delay Effect';
            app.TransportDelayEffectSwitch = uiswitch(app.InterdependenceTransporationDelayPanel, 'slider');
            app.TransportDelayEffectSwitch.Items = {'OFF', 'ON'};
            app.TransportDelayEffectSwitch.ValueChangedFcn = createCallbackFcn(app, @TransportDelayEffectSwitchValueChanged, true);
            app.TransportDelayEffectSwitch.Value = 'ON';
            app.TaskDurationDelayCoefficientSpinnerLabel = uilabel(app.InterdependenceTransporationDelayPanel);
            app.TaskDurationDelayCoefficientSpinnerLabel.Text = 'Task Duration Delay Coefficient';
            app.TaskDurationDelayCoefficientSpinner = uispinner(app.InterdependenceTransporationDelayPanel);
            app.TaskDurationDelayCoefficientSpinner.Step = 0.1;
            app.TaskDurationDelayCoefficientSpinner.Limits = [0 Inf];
            app.TaskDurationDelayCoefficientSpinner.Value = 2;
            app.Qtran0TransportationFunctionalityThresholdLabel = uilabel(app.InterdependenceTransporationDelayPanel);
            app.Qtran0TransportationFunctionalityThresholdLabel.Text = 'Qtran0 (Transportation Functionality Threshold )';
            app.Qtran0TransportationFunctionalityThresholdSpinner = uispinner(app.InterdependenceTransporationDelayPanel);
            app.Qtran0TransportationFunctionalityThresholdSpinner.Step = 0.1;
            app.Qtran0TransportationFunctionalityThresholdSpinner.Limits = [0 Inf];
            app.Qtran0TransportationFunctionalityThresholdSpinner.Tooltip = {'The transportation functionality threshold'; ' delaying in effect is Qtrans<Qtrans0'};
            app.Qtran0TransportationFunctionalityThresholdSpinner.Value = 0.8;
            app.belowwhichtheDelayEffectistriggeredLabel = uilabel(app.InterdependenceTransporationDelayPanel);
            app.belowwhichtheDelayEffectistriggeredLabel.Text = 'below which the Delay Effect is triggered';
            app.FunctionalityMetricSelectionPanel_2 = uipanel(app.TransportationTab);
            app.FunctionalityMetricSelectionPanel_2.Tooltip = {'Choose metric(s) of the system functionality'};
            app.FunctionalityMetricSelectionPanel_2.Title = 'Functionality Metric Selection';
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3 = uipanel(app.FunctionalityMetricSelectionPanel_2);
            app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3.Title = 'Functionality Metrics related to Graph Theory';
            app.AveragedegreeCheckBox_3 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3);
            app.AveragedegreeCheckBox_3.Text = 'Average degree';
            app.CharacteristicpathlengthCheckBox_3 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3);
            app.CharacteristicpathlengthCheckBox_3.Text = 'Characteristic path length';
            app.NetworkefficiencyCheckBox_3 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3);
            app.NetworkefficiencyCheckBox_3.Text = 'Network efficiency';
            app.ClusteringcoefficientclosedneighborhoodCheckBox_3 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3);
            app.ClusteringcoefficientclosedneighborhoodCheckBox_3.Text = 'Clustering coefficient (closed neighborhood)';
            app.LocalefficiencyclosedneighborhoodCheckBox_3 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3);
            app.LocalefficiencyclosedneighborhoodCheckBox_3.Text = 'Local efficiency (closed neighborhood)';
            app.ClusteringcoefficientopenneighborhoodCheckBox_3 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3);
            app.ClusteringcoefficientopenneighborhoodCheckBox_3.Enable = 'off';
            app.ClusteringcoefficientopenneighborhoodCheckBox_3.Text = 'Clustering coefficient (open neighborhood)';
            app.LocalefficiencyopenneighborhoodCheckBox_3 = uicheckbox(app.FunctionalityMetricsrelatedtoGraphTheoryPanel_3);
            app.LocalefficiencyopenneighborhoodCheckBox_3.Enable = 'off';
            app.LocalefficiencyopenneighborhoodCheckBox_3.Text = 'Local efficiency (open neighborhood)';
            app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3 = uipanel(app.FunctionalityMetricSelectionPanel_2);
            app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3.Title = 'Functionality Metrics not related to Graph Theory';
            app.PercentageoffunctionalroadsegmentsbynumberCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3);
            app.PercentageoffunctionalroadsegmentsbynumberCheckBox.Text = 'Percentage of functional road segments by number';
            app.PercentageoffunctionalroadsegmentsbynumberCheckBox.Value = true;
            app.PercentageoffunctionalroadsegmentsbylengthCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3);
            app.PercentageoffunctionalroadsegmentsbylengthCheckBox.Tooltip = {'(1)length/or(2)AADT/or(3)length*AADT'};
            app.PercentageoffunctionalroadsegmentsbylengthCheckBox.Text = 'Percentage of functional road segments by length';
            app.PercentageoffunctionalroadsegmentsbylengthCheckBox.Value = true;
            app.PercentageoffunctionalroadsegmentsbytrafficAADTCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3);
            app.PercentageoffunctionalroadsegmentsbytrafficAADTCheckBox.Tooltip = {'(1)length/or(2)AADT/or(3)length*AADT'};
            app.PercentageoffunctionalroadsegmentsbytrafficAADTCheckBox.Text = 'Percentage of functional road segments by traffic (AADT)';
            app.PercentageoffunctionalroadsegmentsbytrafficAADTCheckBox.Value = true;
            app.PercentageoffunctionalroadsegmentsbylengthtrafficCheckBox = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3);
            app.PercentageoffunctionalroadsegmentsbylengthtrafficCheckBox.Tooltip = {'(1)length/or(2)AADT/or(3)length*AADT'};
            app.PercentageoffunctionalroadsegmentsbylengthtrafficCheckBox.Text = 'Percentage of functional road segments by length * traffic';
            app.PercentageoffunctionalroadsegmentsbylengthtrafficCheckBox.Value = true;
            app.PercendtageoffunctionalroadsegmentsbylengthCheckBox_4 = uicheckbox(app.FunctionalityMetricsnotrelatedtoGraphTheoryPanel_3);
            app.PercendtageoffunctionalroadsegmentsbylengthCheckBox_4.Tooltip = {'(1)length/or(2)AADT/or(3)length*AADT'};
            app.PercendtageoffunctionalroadsegmentsbylengthCheckBox_4.Text = 'Percentage of population with road access at municipality-level';
            app.PercendtageoffunctionalroadsegmentsbylengthCheckBox_4.Value = true;
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_2Label = uilabel(app.FunctionalityMetricSelectionPanel_2);
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_2Label.Text = 'Graph Theory Properties for System Functionality';
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_2 = uiswitch(app.FunctionalityMetricSelectionPanel_2, 'slider');
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_2.Items = {'OFF', 'ON'};
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_2.ValueChangedFcn = createCallbackFcn(app, @GraphTheoryPropertiesforSystemFunctionalitySwitch_2ValueChanged, true);
            app.GraphTheoryPropertiesforSystemFunctionalitySwitch_2.Value = 'OFF';
            app.ResiilienceMetricSelectionPanel_3 = uipanel(app.TransportationTab);
            app.ResiilienceMetricSelectionPanel_3.Tooltip = {'Choose metric(s) of resilience'};
            app.ResiilienceMetricSelectionPanel_3.Title = 'Resiilience Metric Selection';
            app.ResilienceIndexCheckBox_3 = uicheckbox(app.ResiilienceMetricSelectionPanel_3);
            app.ResilienceIndexCheckBox_3.Text = 'Resilience Index';
            app.ResilienceIndexCheckBox_3.Value = true;
            app.ResilienceLossCheckBox_3 = uicheckbox(app.ResiilienceMetricSelectionPanel_3);
            app.ResilienceLossCheckBox_3.Text = 'Resilience Loss';
            app.ResilienceLossCheckBox_3.Value = true;
            app.RapidityCheckBox_3 = uicheckbox(app.ResiilienceMetricSelectionPanel_3);
            app.RapidityCheckBox_3.Text = 'Rapidity';
            app.RapidityCheckBox_3.Value = true;
            app.RestorationTimeCheckBox_3 = uicheckbox(app.ResiilienceMetricSelectionPanel_3);
            app.RestorationTimeCheckBox_3.Text = 'Restoration Time';
            app.RestorationTimeCheckBox_3.Value = true;
            app.ResourceConstraintsPanel_2 = uipanel(app.TransportationTab);
            app.ResourceConstraintsPanel_2.Tooltip = {'Set up how many transportation resources are avilable for restoration activities'};
            app.ResourceConstraintsPanel_2.Title = 'Resource Constraints';
            app.CrewSpinner_3Label = uilabel(app.ResourceConstraintsPanel_2);
            app.CrewSpinner_3Label.Text = 'Crew';
            app.CrewSpinner_3 = uispinner(app.ResourceConstraintsPanel_2);
            app.CrewSpinner_3.Limits = [0 Inf];
            app.CrewSpinner_3.Value = 60;
            app.EquippedTruckSpinnerLabel = uilabel(app.ResourceConstraintsPanel_2);
            app.EquippedTruckSpinnerLabel.Text = 'Equipped Truck';
            app.EquippedTruckSpinner = uispinner(app.ResourceConstraintsPanel_2);
            app.EquippedTruckSpinner.Limits = [0 Inf];
            app.EquippedTruckSpinner.Value = 60;
            app.CraneSpinnerLabel = uilabel(app.ResourceConstraintsPanel_2);
            app.CraneSpinnerLabel.Text = 'Crane';
            app.CraneSpinner = uispinner(app.ResourceConstraintsPanel_2);
            app.CraneSpinner.Limits = [0 Inf];
            app.CraneSpinner.Value = 30;
            app.ConcreteMachineSpinnerLabel = uilabel(app.ResourceConstraintsPanel_2);
            app.ConcreteMachineSpinnerLabel.Text = 'Concrete Machine';
            app.ConcreteMachineSpinner = uispinner(app.ResourceConstraintsPanel_2);
            app.ConcreteMachineSpinner.Limits = [0 Inf];
            app.ConcreteMachineSpinner.Value = 30;
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Tooltip = {''};
            app.AboutTab.Title = 'About';
            app.SponsoringUniversityPanel = uipanel(app.AboutTab);
            app.SponsoringUniversityPanel.Title = 'Sponsoring University';
            app.ImageLU = uiimage(app.SponsoringUniversityPanel);
            app.ImageLU.ImageClickedFcn = createCallbackFcn(app, @ImageLUClicked, true);
            app.ImageLU.ImageSource = 'lehigh.png';
            app.ImageFAU = uiimage(app.SponsoringUniversityPanel);
            app.ImageFAU.ImageClickedFcn = createCallbackFcn(app, @ImageFAUClicked, true);
            app.ImageFAU.ImageSource = 'FAU.jpg';
            app.ImageGSU = uiimage(app.SponsoringUniversityPanel);
            app.ImageGSU.ImageClickedFcn = createCallbackFcn(app, @ImageGSUClicked, true);
            app.ImageGSU.ImageSource = 'gsu.gif';
            app.SponsoringAgencyPanel = uipanel(app.AboutTab);
            app.SponsoringAgencyPanel.Title = 'Sponsoring Agency';
            app.ImageNSF = uiimage(app.SponsoringAgencyPanel);
            app.ImageNSF.ImageClickedFcn = createCallbackFcn(app, @ImageNSFClicked, true);
            app.ImageNSF.ImageSource = 'NSF.jpg';
            app.TextAreaNSF = uitextarea(app.SponsoringAgencyPanel);
            app.TextAreaNSF.Value = {'PRAISys is supprted by National Science Foundation (NSF) through the Critical Resilient Interdependent Infrastructure Systems and Processes (CRISP) program under two collaborative grants: CMMI-1541177 and CMMI-1541089.'};
            app.CMMI1541177Button = uibutton(app.SponsoringAgencyPanel, 'push');
            app.CMMI1541177Button.ButtonPushedFcn = createCallbackFcn(app, @CMMI1541177ButtonPushed, true);
            app.CMMI1541177Button.Text = 'CMMI-1541177';
            app.CMMI1541089Button = uibutton(app.SponsoringAgencyPanel, 'push');
            app.CMMI1541089Button.ButtonPushedFcn = createCallbackFcn(app, @CMMI1541089ButtonPushed, true);
            app.CMMI1541089Button.Text = 'CMMI-1541089';
            app.PRAISysPanel = uipanel(app.AboutTab);
            app.PRAISysPanel.Title = 'PRAISys';
            app.ImagePRAISys = uiimage(app.PRAISysPanel);
            app.ImagePRAISys.ImageClickedFcn = createCallbackFcn(app, @ImagePRAISysClicked, true);
            app.ImagePRAISys.ImageSource = 'praisys.jpg';
            app.TextAreaPRAISys = uitextarea(app.PRAISysPanel);
            app.TextAreaPRAISys.Value = {'PRAISys stands for Probabilistic Resilience Assessment of Interdependent Systems. It is a comprehensive computational platform for supporting pre-event decision making in disaster management under uncertainty. The PRAISys platform can carry out the community resilience assessment under a given hazard scenario by addressing complex infrastructure interdependencies and large uncertainties. '; ''; 'Contact: praisys@lehigh.edu '; 'http://praisys.org'};
            app.LearnmoreonthePRAISyswebsiteButton = uibutton(app.PRAISysPanel, 'push');
            app.LearnmoreonthePRAISyswebsiteButton.ButtonPushedFcn = createCallbackFcn(app, @LearnmoreonthePRAISyswebsiteButtonPushed, true);
            app.LearnmoreonthePRAISyswebsiteButton.Text = 'Learn more on the PRAISys website';
            app.ReadPRAISysTechnicalManualButton = uibutton(app.PRAISysPanel, 'push');
            app.ReadPRAISysTechnicalManualButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPRAISysTechnicalManualButtonPushed, true);
            app.ReadPRAISysTechnicalManualButton.Text = 'Read PRAISys Technical Manual';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Press Run after Complete the User Input'};
            app.RunButton.Text = 'Run';
            app.DoneLabel = uilabel(app.UIFigure);
            app.DoneLabel.Visible = 'off';
            app.DoneLabel.Text = 'Done!';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tooltip = {'Press Stop to Terminate the Computation'};
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRAISysUserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end